classdef GUI_TJX_LeeLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AnimalIDEditField             matlab.ui.control.EditField
        AnimalIDEditFieldLabel        matlab.ui.control.Label
        UserDropDown                  matlab.ui.control.DropDown
        UserDropDownLabel             matlab.ui.control.Label
        ProjectDropDown               matlab.ui.control.DropDown
        ProjectDropDownLabel          matlab.ui.control.Label
        ExperimentEditField           matlab.ui.control.EditField
        ExperimentEditFieldLabel      matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ISITab                        matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        CorrelationTab                matlab.ui.container.Tab
        UIAxes5                       matlab.ui.control.UIAxes
        ParametersfileEditField       matlab.ui.control.EditField
        ParametersfileEditFieldLabel  matlab.ui.control.Label
        SearchButtonXFile             matlab.ui.control.Button
        RESETButton                   matlab.ui.control.Button
        PCDropDown                    matlab.ui.control.DropDown
        PCDropDownLabel               matlab.ui.control.Label
        SAVEButton                    matlab.ui.control.Button
        PLOTButton                    matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        CLUSTERDropDown_2             matlab.ui.control.DropDown
        SelectClusterDropDown         matlab.ui.control.DropDown
        SelectClusterDropDownLabel    matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
        datadirectoryEditField        matlab.ui.control.EditField
        datadirectoryEditFieldLabel   matlab.ui.control.Label
        UIAxes8                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %{
        Edit Log
        27/11/2020 JHL
        added functions for 2D stim, including function
        index_stim. modified functions and code in "PlotButtonPushed"

        01/12/2020 Saul Meza
        Modified PlotButtonPushed (now plotting
        using a class called tjxS_plots)

        07/26/2022 JHL
        Changed name to GUI_TJX_Leelab 
        Changed tjx to only show Waveforms, PCA, SNR, ISI and unit
        positions.
        %}


        ParxFile;
        SearchPath;
        out; %Output from tjxS
        out2;
        clust=[];
        value = "Empty";
        value2 = "Empty";
        valor;
        valor2;
        ids;
        ids2;
        project_name="Empty";
        PC_name="Empty";
        stim_info = {};
        plots;
        path_inp;
%         out_su; %New latest edit
        util = tjx_util;
        user_name ='Empty';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            cla(app.UIAxes8);
            app.RUNButton.BackgroundColor = [0.5 0.5 0.5];
            app.path_inp = app.datadirectoryEditField.Value;
            app.user_name = app.UserDropDown.Value;
%             app.SearchPath = fullfile('D:\Data\',app.user_name,app.path_inp);
%             fpath2 = app.SearchPath;
            
            app.PC_name = app.PCDropDown.Value;
            

            if (app.user_name == "Empty")||(app.PC_name == "Empty")
                app.TextArea.Value = 'Please verify your inputs and run again';
            else
                tic;
                app.TextArea.Value = 'Loading, please wait...';
                
                PF = erase(app.ParxFile, '.m');
                ParamsFile = str2func(PF);
                app.out = tjxS(ParamsFile,app.PC_name, app.user_name,app.path_inp);
                app.ProjectDropDown.Items = ['Empty',app.out.params.projects];
                app.out.initialize;
                pause(0.1);
                if strcmp(app.out.params.file_type,'TDT')
                    app.out.load_data_TDT;
                elseif strcmp(app.out.params.file_type,'OE')
                    app.out.load_data_OE;
                elseif strcmp(app.out.params.file_type,'BR')
                    app.out.load_data_NEV;
                end

                pause(0.1);
                app.out.extract_units;
                pause(0.1);
%                 [~, app.out_su] = app.out.Single_units;
                pause(0.1);
                app.clust=string(app.out.clusters);
                app.SelectClusterDropDown.Items=['Empty',app.clust];
                app.CLUSTERDropDown_2.Items=['Empty',app.clust];
                
                h=figure();
                set(h, 'Visible', 'off');
                
                A = app.out.good_ch;
                
%                 chmap=[1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 ...
%                     37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64];
                chmap = 1: app.out.params.Nb_ch;
%                 x_add=[0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6 0 4 2 6];
%                 y_add=[32 32 31 31 30 30 29 29 28 28 27 27 26 26 25 25 24 24 23 23 22 22 21 21 20 20 19 19 18 18 17 17 16 16 15 15 14 14 13 13 12 12 11 11 10 10 9 9 8 8 7 7 6 6 5 5 4 4 3 3 2 2 1 1 0 0];

                x_add = app.out.params.xcoords*4;
%                 y_add = flipud(app.out.params.ycoords)+2;
                y_add = (app.out.params.ycoords)+2;

                cla(app.UIAxes8)
                if ismember(A, chmap)
                    [~,Locb]=ismember(A, chmap);
                    
                    for i=1:length(A)
                        id = app.out.idds(i);
                        c=x_add(Locb(i));
                        d=(y_add(Locb(i)))*100;
                        time_step = ([1:60]/app.out.params.fs*1e3)+c;
                        waveform=(app.out.waveforms.mean{id}*0.5)+d; %scaling waveform to fit with plot
                        plot(app.UIAxes8,time_step,waveform,'Linewidth',2);
                        text(app.UIAxes8,c+1.25,d-25 + randsample([-10:10],1)*5,app.clust(i),'FontWeight','bold');
                        hold on;
                        ylim(app.UIAxes8,[0 (max(y_add)+1)*100]);
                        xlim(app.UIAxes8,[min(x_add)-1 max(x_add)+4]);
%                         yticks(app.UIAxes8,0:128:3200);
%                         yticklabels(app.UIAxes8,{'625','600','575','550','525','500','475','450','425','400','375','350','325','300','275','250','225','200','175','150','125','100','75','50','25','0'});
                        ylabel(app.UIAxes8,'um');
                    end
                    
                end
                % calling stim info
%                 if isempty(app.Stim2D.Value)
%                     app = app.util.index_stim(app);
%                 elseif strcmp(app.Stim2D.Value,'RHS')
%                 else
%                     app = app.util.index_stim2(app);
%                 end
%                 
                
                toc;
                app.TextArea.Value = 'Data loaded, ready to plot';
                
            end
            
        end

        % Value changed function: SelectClusterDropDown
        function SelectClusterDropDownValueChanged(app, event)
            
            app.value = app.SelectClusterDropDown.Value;
            app.valor = str2double(app.value);
            app.ids = app.out.idds(app.out.clusters==app.valor);
            
        end

        % Value changed function: CLUSTERDropDown_2
        function CLUSTERDropDown_2ValueChanged(app, event)

            app.value2 = app.CLUSTERDropDown_2.Value;
            app.valor2 = str2double(app.value2);
            app.ids2 = app.out.idds(app.out.clusters==app.valor2);

        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
            h=figure();
            set(h, 'Visible', 'off');


            % NEW CODE, CALLING CLASS FOR PLOTTING
            app.plots = tjxS_plots(app.stim_info,app.out, app.value, app.value2, app.UIAxes, app.UIAxes2, ...
                app.UIAxes4,app.UIAxes5);
            
            if (app.value=="Empty")&&(app.value2=="Empty")
                
                app.TextArea.Value = 'Please select a cluster to plot';
                app.plots.clear_all;
                
            elseif (app.value~="Empty")&&(app.value2=="Empty")
                
                app.TextArea.Value = 'Selection 1 plotted';
                app.plots.clear_all;
                app.plots.waveform(1, app.ids);
                app.plots.isi_plot(1, app.ids);
                app.plots.pca_plot(1, app.ids);
                app.plots.corr_plot(1,app.ids);
%                 if isempty(app.Stim2D.Value)
%                     app.plots.tuning_curve(1, app.ids);
%                     app.plots.raster(1, app.ids)
%                 elseif strcmp(app.Stim2D.Value,'RHS')
%                     app.plots.RHSplot(1, app.ids);
%                 else
%                     app.plots.tuning2(1,app.ids,app.Stim1Para.Value,app.Stim2Para.Value);
%                     app.plots.raster2(1,app.ids,app.Stim1Para.Value,app.Stim2Para.Value)
%                 end
                
            elseif (app.value=="Empty")&&(app.value2~="Empty")
                
                app.TextArea.Value = 'Selection 2 plotted';
                app.plots.clear_all;
                app.plots.waveform(2, app.ids2);
                app.plots.isi_plot(2, app.ids2);
                app.plots.pca_plot(2, app.ids2);
                app.plots.corr_plot(2,app.ids2);

%                 if isempty(app.Stim2D.Value)
%                     app.plots.tuning_curve(2, app.ids2);
%                     app.plots.raster(2, app.ids2)
%                 elseif strcmp(app.Stim2D.Value,'RHS')
%                     app.plots.RHSplot(2, app.ids2);
%                 else
%                     app.plots.tuning2(2,app.ids2,app.Stim1Para.Value,app.Stim2Para.Value);
%                     app.plots.raster2(2,app.ids2,app.Stim1Para.Value,app.Stim2Para.Value);
%                 end
                
            else
                
                app.TextArea.Value = 'Both selections plotted';
                app.plots.clear_all;
                app.plots.waveform(3, [app.ids, app.ids2]);
                app.plots.isi_plot(3, [app.ids, app.ids2]);
                app.plots.pca_plot(3, [app.ids, app.ids2]);
                app.plots.corr_plot(3,[app.ids, app.ids2]);

                %                 if isempty(app.Stim2D.Value)
%                     app.plots.tuning_curve(3, [app.ids, app.ids2]);
%                     app.plots.raster(3, [app.ids, app.ids2])
%                 else
% %                     app.plots.tuning2(3, [app.ids, app.ids2]);
%                     %                     app.plots.raster2(3, [app.ids, app.ids2])
%                     app.TextArea.Value = 'Not plotting raster due to load';
%                 end
                
            end
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)

            %             mkdir('C:\Users\Seth\Documents\GitHub\Kilosort-Wanglab\tjxS\Dummy_Save_Folder', app.animal_name); % MODIFY THIS LINE DEPENDING ON WHICH FOLDER YOU WANT TO SAVE THE DATA
            %             sdir = fullfile('C:\Users\Seth\Documents\GitHub\Kilosort-Wanglab\tjxS\Dummy_Save_Folder', app.animal_name); % MODIFY THIS LINE TOO DEPENDING ON WHICH FOLDER YOU WANT TO SAVE THE DATA

            %             mkdir('D:\Data\Units', app.animal_name); % MODIFY THIS LINE DEPENDING ON WHICH FOLDER YOU WANT TO SAVE THE DATA
            %             sdir = fullfile('D:\Data\Units', app.animal_name); % MODIFY THIS LINE TOO DEPENDING ON WHICH FOLDER YOU WANT TO SAVE THE DATA
            
            app.project_name = app.ProjectDropDown.Value;
            if isempty(app.clust)
                app.TextArea.Value = 'Please input the data and run the GUI first';
            elseif isempty(app.ExperimentEditField)
                app.TextArea.Value = 'Please input Experiment';
            elseif strcmp(app.project_name,'Empty')
                app.TextArea.Value = 'Please Select Project name';
            elseif isempty(app.AnimalIDEditField)
                app.TextArea.Value = 'Please input Animal ID';
          
            else
                sdir = fullfile(app.out.params.savepath,app.ProjectDropDown.Value);


                app.TextArea.Value = 'Saving data...';
                pause(0.2);
                app.out.save_Units(sdir,app.project_name,app.path_inp,app.AnimalIDEditField.Value,app.ExperimentEditField.Value);
                app.TextArea.Value = 'Data saved...';
                %                     end
                %                 else
                %
                %                 end
            end

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            %Clear all the results and previous inputs in order to run the GUI again
            app.RUNButton.BackgroundColor = [0.94 0.94 0.94];
            try
                app.plots.clear_all;
            catch
                app.TextArea.Value = 'ERR: No plots';
            end
            delete(get(app.UIAxes8, 'children'));
            
            app.datadirectoryEditField.Value = '';
            app.ParametersfileEditField.Value = '';
%             app.SessionIDEditField.Value = 0;
            app.project_name = "Empty";
            app.user_name = "Empty";
            app.PC_name = "Empty";
            
            app.clust = [];
            app.value = "Empty";
            app.value2 = "Empty";
            
            app.SelectClusterDropDown.Items = {'Empty'};
            app.CLUSTERDropDown_2.Items = {'Empty'};
            
            app.PCDropDown.Items = {'Empty'};
            app.ProjectDropDown.Items = {'Empty'};
            app.UserDropDown.Items = {'Empty'};
            
            
            app.TextArea.Value = 'Values cleared, ready to run again';
            

        end

        % Button pushed function: SearchButtonXFile
        function SearchButtonXFilePushed(app, event)
            [app.ParxFile,PathXfile] = uigetfile; %Getting the file and its path
            app.ParametersfileEditField.Value = fullfile(PathXfile,app.ParxFile); %Showing the path to the parameters file
            app.out2 = data_file(); %Getting the data file to obtain the list of PC names and animal names
%             PCNamelist = app.out2.PC_list;
            app.PCDropDown.Items = ['Empty',app.out2.PC_list];
%             Userlist = app.out2.User_list;
            app.UserDropDown.Items = ['Empty',app.out2.User_list];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PCA')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'SNR')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Unit positions')
            app.UIAxes8.XTick = [];
            app.UIAxes8.XTickLabel = '';
            app.UIAxes8.YTick = [];
            app.UIAxes8.YTickLabel = '';
            app.datadirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.datadirectoryEditFieldLabel.Text = 'data directory';
            app.datadirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.SelectClusterDropDownLabel = uilabel(app.UIFigure);
            app.SelectClusterDropDownLabel.Text = 'Select Cluster:';
            app.SelectClusterDropDown = uidropdown(app.UIFigure);
            app.SelectClusterDropDown.Items = {'Option 1'};
            app.SelectClusterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectClusterDropDownValueChanged, true);
            app.CLUSTERDropDown_2 = uidropdown(app.UIFigure);
            app.CLUSTERDropDown_2.Items = {'Option 1'};
            app.CLUSTERDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CLUSTERDropDown_2ValueChanged, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.PCDropDownLabel = uilabel(app.UIFigure);
            app.PCDropDownLabel.Text = 'PC';
            app.PCDropDown = uidropdown(app.UIFigure);
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = {'RESET'; ''};
            app.SearchButtonXFile = uibutton(app.UIFigure, 'push');
            app.SearchButtonXFile.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonXFilePushed, true);
            app.SearchButtonXFile.Text = 'Search';
            app.ParametersfileEditFieldLabel = uilabel(app.UIFigure);
            app.ParametersfileEditFieldLabel.Text = 'Parameters file';
            app.ParametersfileEditField = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ISITab = uitab(app.TabGroup);
            app.ISITab.Title = 'ISI';
            app.UIAxes4 = uiaxes(app.ISITab);
            title(app.UIAxes4, 'ISI')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.CorrelationTab = uitab(app.TabGroup);
            app.CorrelationTab.Title = 'Correlation';
            app.UIAxes5 = uiaxes(app.CorrelationTab);
            title(app.UIAxes5, 'Correlation')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.UserDropDownLabel = uilabel(app.UIFigure);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.UIFigure);
            app.AnimalIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_TJX_LeeLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end