classdef GUI_TJD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        merge_clusterTab              matlab.ui.container.Tab
        RUNLOCKUNITSButton            matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        TextArea2                     matlab.ui.control.TextArea
        SHOWSELECTEDSPIKESButton      matlab.ui.control.Button
        RESTARTButton                 matlab.ui.control.Button
        REASSIGNTO1Button             matlab.ui.control.Button
        REASSIGNTO2Button             matlab.ui.control.Button
        DELETESPIKESButton            matlab.ui.control.Button
        SAVEALLCHANGESButton          matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        UnitDropDown_3Label           matlab.ui.control.Label
        UnitDropDown_3                matlab.ui.control.DropDown
        UnitDropDown_2Label           matlab.ui.control.Label
        UnitDropDown_2                matlab.ui.control.DropDown
        PLOTButton                    matlab.ui.control.Button
        ChannelDropDownLabel          matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        XBZFileDropDownLabel          matlab.ui.control.Label
        XBZFileDropDown               matlab.ui.control.DropDown
        CONFIRMCHANGESButton          matlab.ui.control.Button
        UNDOButton                    matlab.ui.control.Button
        MERGEALLButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        CdistanceTab                  matlab.ui.container.Tab
        TextArea3                     matlab.ui.control.TextArea
        DistancebetweenDropDownLabel  matlab.ui.control.Label
        DistancebetweenDropDown       matlab.ui.control.DropDown
        UnitDropDownLabel             matlab.ui.control.Label
        UnitDropDown                  matlab.ui.control.DropDown
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
    end

    % Principle Author: Saúl Meza
    
    % Last edit: May 19th, 2021
    
    % Edit log: Fixed bugs related to best channel selection and added
    % error messages
    properties (Access = private)
        unit; %To store unit numbers
        roi; %To run code for spike selection
        waveforms = []; %Waveforms of the selected channel
        spike_times = []; %Spiketimes information from tjd code
        index = []; %Waveforms indexes from tjd code
        all_chan_waveforms = []; %waveforms of all the channels
        channels = []; %Best channels
        index1_save = [];
        index2_save = [];
        all_waveforms = {};
        all_spike_times = {};
        animal_name;
        save_directory;
        segment_list; % XBZ files list
        current = 0; %XBZ file number
        run_pushed = 0;
        score = [];
        segment; %Current XBZ file name
        waveforms_sel = [];
        spike_times_sel = [];
        index_sel = [];
        pi = []; %To store spikes inside ROI selection
        po = []; %To store spikes outside ROI selection
        save_index = []; %Indexes to be saved after modifying units
        sequence;
        d_template;
        N; %Units
        unit_sel_1_val="Empty";
        unit_sel_2_val="Empty";
        channel_sel = "Empty";
        out;
        outs = [];
        channel_val="Empty";
        number_plots = 0;
        unit_numbers;
        distance_items;
        previous_waveforms = [];
        previous_spiketimes = [];
        previous_index = [];
        previous_score = [];
        previous_all_chan_waveforms = [];
        merge_all = 0;
        %xbz_drop_changed = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, segment_list, d_temp, N, sequence, animal_name, save_dir)
            h=figure();
            set(h, 'Visible', 'off');
            
            app.SAVEALLCHANGESButton.Enable = false; %At the begining of the GUI, the SAVE ALL button is not enabled because no modifications to the Units have been made
            app.CONFIRMCHANGESButton.Enable = false;
            app.REASSIGNTO1Button.Enable = false;
            app.REASSIGNTO2Button.Enable = false;
            app.DELETESPIKESButton.Enable = false;
            app.UNDOButton.Enable = false;
            app.RUNLOCKUNITSButton.Enable = false;
            app.SHOWSELECTEDSPIKESButton.Enable = false;
            app.ChannelDropDown.Enable = false;
            app.XBZFileDropDown.Enable = false;
            app.PLOTButton.Enable = false;
            app.MERGEALLButton.Enable = false;
            
            app.UnitDropDown.Items = "Empty";
            app.UnitDropDown_2.Items = "Empty";
            app.ChannelDropDown.Items = "Empty";
            
            app.segment_list = segment_list; % XBZ files
            app.d_template = d_temp;
            app.N = N;
            app.sequence=sequence;
            app.animal_name = animal_name;
            app.save_directory = save_dir;
            app.out = tjdS;
            
            
            for x=1:length(app.segment_list)-1
                app.distance_items{x} = [app.segment_list{x} ' and ' app.segment_list{x+1}];
            end
            for y=1:app.N
                app.unit_numbers{y} = int2str(y);
            end
            
            app.DistancebetweenDropDown.Items = app.distance_items;
            app.UnitDropDown.Items = app.unit_numbers;
            app.UnitDropDown_2.Items = ['Empty' app.unit_numbers];
            app.UnitDropDown_3.Items = ['Empty' app.unit_numbers];
            app.XBZFileDropDown.Items = "Empty";
            
            imagesc(app.UIAxes6, app.d_template{1});
            
            pool1 = [];
            pool2 = [];
            for seg_ls = 1:length(app.segment_list)-1
                pool1 = [pool1 app.d_template{seg_ls}];
                pool2 = [pool2; app.d_template{seg_ls}];
            end
            pool2 = pool2.';
            pool1 = pool1/max(max(pool1));
            pool2 = pool2/max(max(pool2));
            idx =[];
            for i = 1:length(app.segment_list)-1
                idx = [idx 1:app.N];
            end
            cmap = colormap(app.UIAxes7,'jet');
            cmap = cmap(1:2:end,:);
            
            u=1;
            gscatter(app.UIAxes7,pool1(u,:),pool2(u,:),idx,cmap,'o+*^x') %,'doleg','off')
            hold on
            test = sqrt(pool1(u,:).^2 + pool2(u,:).^2);
            grp2 = test(idx ~= u);
            Y1 = prctile(grp2(find(~isnan(grp2))),5); %5 percentile of group 2
            plot(app.UIAxes7,[sqrt(Y1^2+Y1^2),0],[0,sqrt(Y1^2+Y1^2)],'--')
            drawnow;
            title(app.UIAxes7,['Unit number ' num2str(u)]);
            axis(app.UIAxes7,[0 1 0 1]);
            
            app.TextArea.Value = 'GUI Ready, select Unit(s) and XBZ File';
            app.TextArea2.Value = 'GUI Ready, select Unit(s) and XBZ File';
            app.TextArea3.Value = 'Plots ready';
            disp('TJD GUI Ready');
        end

        % Value changed function: UnitDropDown_2
        function UnitDropDown_2ValueChanged(app, event)
            value = app.UnitDropDown_2.Value;
            app.unit_sel_1_val = value;
            app.XBZFileDropDown.Enable = true;
            app.XBZFileDropDown.Items = ['Empty' app.segment_list'];
            app.ChannelDropDown.Enable = false;
            app.PLOTButton.Enable = false;
        end

        % Value changed function: UnitDropDown_3
        function UnitDropDown_3ValueChanged(app, event)
            value = app.UnitDropDown_3.Value;
            app.unit_sel_2_val = value;
            app.XBZFileDropDown.Enable = true;
            app.XBZFileDropDown.Items = ['Empty' app.segment_list'];
            app.ChannelDropDown.Enable = false;
            app.PLOTButton.Enable = false;
        end

        % Value changed function: XBZFileDropDown
        function XBZFileDropDownValueChanged(app, event)
            %app.ChannelDropDown.Items = "Empty";
            disp('Loading channels...');
            value = app.XBZFileDropDown.Value;
            app.current = find(matches(app.XBZFileDropDown.Items, value))-1;
            app.segment = value;
            

            if app.current == 0
                
                app.TextArea.Value = 'Select a valid XBZ File Name';
                app.TextArea2.Value = 'Select a valid XBZ File Name';
                
            else
                if (app.unit_sel_1_val ~= "Empty") && (app.unit_sel_2_val ~= "Empty")
                    u1 = str2double(app.unit_sel_1_val);
                    u2 = str2double(app.unit_sel_2_val);
                    
                    seg_ls = app.current;
                    cluster1 = u1;
                    cluster2 = u2;
                    chnls = [];
                    A = app.sequence(:,seg_ls);
                    
                    if A(cluster1)~= 0 && A(cluster2)~= 0
                        
                        unit_file_name1 = [app.animal_name 'u00000'];
                        unit_file_name1 = [unit_file_name1(1:end-size(num2str(A(cluster1)),2)) num2str(A(cluster1)) '.mat'];
                        x = load(unit_file_name1);
                        chnls = [chnls; [0 x.s_unit.templates.best_ch]];
                        
                        unit_file_name2 = [app.animal_name 'u00000'];
                        unit_file_name2 = [unit_file_name2(1:end-size(num2str(A(cluster2)),2)) num2str(A(cluster2)) '.mat'];
                        y = load(unit_file_name2);
                        chnls = [chnls; [1 y.s_unit.templates.best_ch]];
                        
                        app.ChannelDropDown.Enable = true;
                        app.channels = [chnls(find(chnls==0),2) chnls(find(chnls==1),2)];
                        app.ChannelDropDown.Items = ['Empty' {int2str(chnls(find(chnls==0),2)) int2str(chnls(find(chnls==1),2))}];
                        app.TextArea.Value = 'Channels loaded, select a channel and press PLOT';
                        disp('Channels loaded...');
                        app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                        
                    else
                        if A(cluster1) == 0
                            app.TextArea.Value = ['ERROR: Unit ' app.unit_sel_1_val ' does not exist in current XBZ file, please select a different XBZ file or different unit'];
                        elseif A(cluster2) == 0
                            app.TextArea.Value = ['ERROR: Unit ' app.unit_sel_2_val ' does not exist in current XBZ file, please select a different XBZ file or different unit'];
                        end
                    end
                    
                elseif (app.unit_sel_1_val ~= "Empty") && (app.unit_sel_2_val == "Empty")
                    u = str2double(app.unit_sel_1_val);
                    
                    seg_ls = app.current;
                    A = app.sequence(u,:);
                    chnls = [];
                    if A(seg_ls) ~=0
                        unit_file_name = [app.animal_name 'u00000'];
                        unit_file_name = [unit_file_name(1:end-size(num2str(A(seg_ls)),2)) num2str(A(seg_ls)) '.mat'];
                        x = load(unit_file_name);
                        chnls = [chnls x.s_unit.templates.best_ch];
                        
                        app.ChannelDropDown.Enable = true;
                        app.channels = chnls;
                        app.ChannelDropDown.Items = ['Empty' {int2str(chnls)}];
                        app.TextArea.Value = 'Channels loaded, select a channel and press PLOT';
                        disp('Channels loaded...');
                        app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                    else
                        app.TextArea.Value = ['ERROR: Unit ' app.unit_sel_1_val ' does not exist in current XBZ file, please select a different XBZ file or different unit'];
                    end
                    
                elseif (app.unit_sel_1_val == "Empty") && (app.unit_sel_2_val ~= "Empty")
                    u = str2double(app.unit_sel_2_val);
                    
                    seg_ls = app.current;
                    A = app.sequence(u,:);
                    chnls = [];
                    if A(seg_ls) ~=0
                        unit_file_name = [app.animal_name 'u00000'];
                        unit_file_name = [unit_file_name(1:end-size(num2str(A(seg_ls)),2)) num2str(A(seg_ls)) '.mat'];
                        x = load(unit_file_name);
                        chnls = [chnls x.s_unit.templates.best_ch];
                        
                        app.ChannelDropDown.Enable = true;
                        app.channels = chnls;
                        app.ChannelDropDown.Items = ['Empty' {int2str(chnls)}];
                        app.TextArea.Value = 'Channels loaded, select a channel and press PLOT';
                        disp('Channels loaded...');
                        app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                    else
                        app.TextArea.Value = ['ERROR: Unit ' app.unit_sel_2_val ' does not exist in current XBZ file, please select a different XBZ file or different unit'];
                    end
                    
                end
                
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            app.channel_val = value;
            app.channel_sel = str2double(value);
            app.PLOTButton.Enable = true;
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            app.SHOWSELECTEDSPIKESButton.BackgroundColor = [0.96 0.96 0.96];
            
            if (app.channel_val == "Empty")
                app.TextArea.Value = 'ERROR: Select a channel before pressing PLOT';
                app.TextArea2.Value = 'ERROR: Select a channel before pressing PLOT';
            else
                app.TextArea.Value = 'Loading data, please wait...';
                app.TextArea2.Value = 'Loading data, please wait...';
                app.RUNLOCKUNITSButton.Enable = true;
                app.MERGEALLButton.Enable = true;
                
                
                if (app.unit_sel_1_val ~= "Empty") && (app.unit_sel_2_val ~= "Empty")
                    u1 = str2double(app.unit_sel_1_val);
                    u2 = str2double(app.unit_sel_2_val);
                    units = [u1 u2];
                    
                    [~,app.outs] = app.out.merge_cluster(app.channel_sel,units,app.sequence,app.current);
                    
                    app.unit = units;
                    app.REASSIGNTO1Button.Text = ['REASSIGN TO ' num2str(u1)];
                    app.REASSIGNTO2Button.Text = ['REASSIGN TO ' num2str(u2)];
                    
                    app.segment = app.segment_list{app.current}; % Current XBZ file number selected
                    app.waveforms = app.outs.waveforms_all;
                    assignin('base', 'waveforms', app.waveforms);
                    app.all_chan_waveforms = app.outs.waveforms_chan_all;
                    app.spike_times = app.outs.spike_times;
                    app.index = app.outs.index_all;
                    assignin('base','waveforms_8_original', app.waveforms(:,find(app.index==0)));
                    assignin('base','waveforms_all_chan55_original',reshape(app.all_chan_waveforms(app.channel_sel,:,find(app.index==0)),60,length(find(app.index==0))));
                    assignin('base','waveforms_35_original', app.waveforms(:,find(app.index==1)));
                    

                    [~,app.score,~] = pca(app.waveforms.');
                    
                    idx_cls = app.index;
                    
                    plots = tjdS_plots(app.channels, app.unit, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots.pcas;
                    plots.waveforms;
                    plots.isis;
                    app.TextArea.Value = 'Plots ready, press RUN to select spikes or select another XBZ and plot again';
                    app.TextArea2.Value = ['Current XBZ file: ' string(app.segment) ' Channel Plotted: ' app.channel_sel];
                    app.number_plots = 2;
                        
                    
                elseif (app.unit_sel_1_val ~= "Empty") && (app.unit_sel_2_val == "Empty")
                    u = str2double(app.unit_sel_1_val);
                    
                    [~, app.outs] = app.out.clean_cluster(app.channel_sel,u,app.sequence,app.current);
                    
                    app.unit = u;
                    
                    app.segment = app.segment_list{app.current}; % Initial XBZ file
                    app.waveforms = app.outs.waveforms_all;
                    app.all_chan_waveforms = app.outs.waveforms_chan_all;
                    app.spike_times = app.outs.spike_times;
                    app.index = app.outs.index_all;
                    
                    [~,app.score,~] = pca(app.waveforms.');
                    idx_cls = app.index;
                    
                    plots = tjdS_plots(app.channels, app.unit, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots.pca;
                    plots.waveform;
                    plots.isi;
                    app.TextArea.Value = 'Plots ready, press RUN to select spikes or select another XBZ and plot again';
                    app.TextArea2.Value = ['Current XBZ file: ' string(app.segment) ' Channel Plotted: ' app.channel_sel];
                    app.number_plots = 1;
                    
                elseif (app.unit_sel_1_val == "Empty") && (app.unit_sel_2_val ~= "Empty")
                    u = str2double(app.unit_sel_2_val);
                    
                    [~, app.outs] = app.out.clean_cluster(app.channel_sel,u,app.sequence,app.current);
                    
                    app.unit = u;
                    
                    app.segment = app.segment_list{app.current}; % Initial XBZ file
                    app.waveforms = app.outs.waveforms_all;
                    app.all_chan_waveforms = app.outs.waveforms_chan_all;
                    app.spike_times = app.outs.spike_times;
                    app.index = app.outs.index_all;
                    
                    [~,app.score,~] = pca(app.waveforms.');
                    idx_cls = app.index;
                    
                    plots = tjdS_plots(app.channels, app.unit, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots.pca;
                    plots.waveform;
                    plots.isi;
                    app.TextArea.Value = 'Plots ready, press RUN to select spikes or select another XBZ and plot again';
                    app.TextArea2.Value = ['Current XBZ file: ' string(app.segment) ' Channel Plotted: ' app.channel_sel];
                    app.number_plots = 1;
                end
                
            end
        end

        % Button pushed function: RUNLOCKUNITSButton
        function RUNLOCKUNITSButtonPushed(app, event)
            %app.RUNLOCKUNITSButton.BackgroundColor = [0.5 0.5 0.5];
            app.UnitDropDown_2.Enable = false;
            app.UnitDropDown_3.Enable = false;
            
            if app.number_plots == 0
                app.TextArea.Value = 'Load data and Plot before trying to select spikes';
                
            elseif app.number_plots == 2
                app.SHOWSELECTEDSPIKESButton.Enable = true;
                app.roi = drawpolygon(app.UIAxes,'FaceAlpha',0, 'LineWidth', 3, 'Color', 'yellow', 'MarkerSize', 3);
                %app.roi = drawcircle(app.UIAxes,'FaceAlpha',0, 'LineWidth', 3, 'Color', 'yellow', 'MarkerSize', 3);
                app.roi.Color='yellow';
                app.roi.LineWidth = 2;
                app.roi.MarkerSize = 3;
                app.run_pushed = 1;
                app.RUNLOCKUNITSButton.Enable = false;
            elseif app.number_plots == 1
                app.SHOWSELECTEDSPIKESButton.Enable = true;
                app.roi = drawpolygon(app.UIAxes,'FaceAlpha',0, 'LineWidth', 3, 'Color', 'yellow', 'MarkerSize', 3);
                app.roi.Color='yellow';
                app.roi.LineWidth = 2;
                app.roi.MarkerSize = 3;
                app.run_pushed = 1;
                app.RUNLOCKUNITSButton.Enable = false;
            end
            
        end

        % Button pushed function: SHOWSELECTEDSPIKESButton
        function SHOWSELECTEDSPIKESButtonPushed(app, event)
            if app.run_pushed ~= 1
                app.TextArea.Value = 'Press RUN and select the spikes first';
                
            elseif app.run_pushed == 1
                if app.number_plots == 2
                    app.REASSIGNTO1Button.Enable = true;
                    app.REASSIGNTO2Button.Enable = true;
                    app.DELETESPIKESButton.Enable = true;
                    app.MERGEALLButton.Enable = true;
                elseif app.number_plots == 1
                    app.DELETESPIKESButton.Enable = true;
                end
                app.run_pushed = 2;
                
                app.pi = inROI(app.roi,app.score(:,1),app.score(:,2)); %Locate points inside selection
                app.po = not(app.pi); %Locate points outside selection
                
                app.waveforms_sel = app.waveforms(:,app.pi);
                app.spike_times_sel = app.spike_times(app.pi);
                app.index_sel = app.index(app.pi); %Indexes of selected spikes
                idx_cls = app.index_sel;
                assignin('base','Number_spikes_selected',length(idx_cls));
                
                id1=find(idx_cls==0);
                id2=find(idx_cls==1);
                
                if length(id1)<120
                    idx1 = id1;
                else
                    idx1 = randsample(id1,120);
                end
                
                for a = idx1
                    plot(app.UIAxes5,app.waveforms_sel(:,a));
                end
                
                if length(id2)<120
                    idx2 = id2;
                else
                    idx2 = randsample(id2,120);
                end
                
                for b = idx2
                    plot(app.UIAxes5,app.waveforms_sel(:,b));
                end
                
                app.TextArea.Value = 'Spikes selected plotted';
                app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                app.SHOWSELECTEDSPIKESButton.Enable = false;
            end
            
        end

        % Button pushed function: MERGEALLButton
        function MERGEALLButtonPushed(app, event)
            app.previous_index = app.index;
            app.previous_waveforms = app.waveforms;
            app.previous_spiketimes = app.spike_times;
            app.previous_score = app.score;
            %app.RUNLOCKUNITSButton.Enable = false;

            %selection = questdlg('In which cluster do you want to save all the spikes?', 'MERGE ALL SPIKES', app.unit_sel_1_val, app.unit_sel_2_val, 'Cancel');
            msg = 'In which cluster do you want to save all the spikes?';
            title = 'MERGE ALL SPIKES';
            selection = uiconfirm(app.UIFigure, msg, title, 'Options', {app.unit_sel_1_val, app.unit_sel_2_val, 'Cancel'}, 'DefaultOption', 3, 'CancelOption', 3);
            
            
            switch selection
                case app.unit_sel_1_val
                    app.merge_all = 1;
                    
                    for i=find(app.index)
                        app.index(i) = 0;
                    end
                    idx_cls = app.index;
                    app.save_index = idx_cls;
                    assignin('base','waveforms_8_modified',app.waveforms(:,find(app.save_index==0)));
                    assignin('base','waveforms_35_modified',0);
                    
                    u = str2double(app.unit_sel_1_val);
                    
                    plots_r1 = tjdS_plots(app.channels, u, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_r1.clear_all;
                    plots_r1.pca;
                    plots_r1.waveform;
                    plots_r1.isi;
                    app.UNDOButton.Enable = true;
                    
                    app.TextArea.Value = ['All spikes reassigned to Unit ' app.unit_sel_1_val ', confirm changes?'];
                    app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                    app.CONFIRMCHANGESButton.Enable = true;
                    app.UNDOButton.Enable = true;
                    app.REASSIGNTO1Button.Enable = false;
                    app.REASSIGNTO2Button.Enable = false;
                    app.DELETESPIKESButton.Enable = false;
                    app.MERGEALLButton.Enable = false;
                    
                case app.unit_sel_2_val
                    app.merge_all = 2;
                    for i=find(app.index)
                        app.index(i) = 1;
                    end
                    idx_cls = app.index;
                    app.save_index = idx_cls;
                    assignin('base','waveforms_8_modified',0);
                    assignin('base','waveforms_35_modified',app.waveforms(:,find(app.save_index==1)));
                    
                    u = str2double(app.unit_sel_2_val);
                    
                    plots_r1 = tjdS_plots(app.channels, u, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_r1.clear_all;
                    plots_r1.pca;
                    plots_r1.waveform;
                    plots_r1.isi;
                    app.UNDOButton.Enable = true;
                    
                    app.TextArea.Value = ['All spikes reassigned to Unit ' app.unit_sel_2_val];
                    app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                    app.CONFIRMCHANGESButton.Enable = true;
                    app.UNDOButton.Enable = true;
                    app.REASSIGNTO1Button.Enable = true;
                    app.REASSIGNTO2Button.Enable = true;
                    app.DELETESPIKESButton.Enable = true;
                    app.MERGEALLButton.Enable = true;
                    
            end
        end

        % Button pushed function: REASSIGNTO1Button
        function REASSIGNTO1ButtonPushed(app, event)
            if app.run_pushed ~= 2
                
                app.TextArea.Value = 'Press SHOW SELECTED SPIKES button before reassigning them';
                
            elseif app.run_pushed == 2
                
                app.previous_index = app.index;
                app.previous_waveforms = app.waveforms;
                app.previous_spiketimes = app.spike_times;
                app.previous_score = app.score;
                
                index_pi=find(app.pi); %Finding indexes of spikes selected
                
                %Assigning an index value of zero to the points inside
                %selection (to assign them to the first Unit)
                for i=index_pi
                    app.index(i) = 0;
                end
                idx_cls = app.index;
                app.save_index = idx_cls;
                
                assignin('base','waveforms_8_modified',app.waveforms(:,find(app.save_index==0)));
                assignin('base','waveforms_35_modified',app.waveforms(:,find(app.save_index==1)));
                
                if app.number_plots == 2
                    plots_r1 = tjdS_plots(app.channels, app.unit, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_r1.clear_all;
                    plots_r1.pcas;
                    plots_r1.waveforms;
                    plots_r1.isis;
                elseif app.number_plots == 1
                    plots_r1 = tjdS_plots(app.channels, app.unit, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_r1.clear_all;
                    plots_r1.pca;
                    plots_r1.waveform;
                    plots_r1.isi;
                end
                
                app.TextArea.Value = ['Selected spikes reassigned to Unit ' num2str(app.unit(1))];
                app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                app.CONFIRMCHANGESButton.Enable = true;
                app.UNDOButton.Enable = true;
                app.REASSIGNTO1Button.Enable = true;
                app.REASSIGNTO2Button.Enable = true;
                app.DELETESPIKESButton.Enable = true;
                app.MERGEALLButton.Enable = true;
%                 app.REASSIGNTO1Button.Enable = false;
%                 app.REASSIGNTO2Button.Enable = false;
%                 app.DELETESPIKESButton.Enable = false;
%                 app.MERGEALLButton.Enable = false;
            end
            
        end

        % Button pushed function: REASSIGNTO2Button
        function REASSIGNTO2ButtonPushed(app, event)
            if app.run_pushed ~= 2
                
                app.TextArea.Value = 'Press SHOW SELECTED SPIKES button before reassigning them';
                
            elseif app.run_pushed == 2
                
                app.previous_index = app.index;
                app.previous_waveforms = app.waveforms;
                app.previous_spiketimes = app.spike_times;
                app.previous_score = app.score;
                
                index_pi=find(app.pi); %Finding indexes of spikes selected
                
                %Assigning an index value of zero to the points inside
                %selection (to assign them to the second Unit)
                for i=index_pi
                    app.index(i) = 1;
                end
                idx_cls = app.index;
                app.save_index = idx_cls;
                
                assignin('base','waveforms_8_modified',app.waveforms(:,find(app.save_index==0)));
                assignin('base','waveforms_35_modified',app.waveforms(:,find(app.save_index==1)));
                
                if app.number_plots == 2
                    plots_r1 = tjdS_plots(app.channels, app.unit, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_r1.clear_all;
                    plots_r1.pcas;
                    plots_r1.waveforms;
                    plots_r1.isis;
                elseif app.number_plots == 1
                    plots_r1 = tjdS_plots(app.channels, app.unit, app.score, idx_cls, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_r1.clear_all;
                    plots_r1.pca;
                    plots_r1.waveform;
                    plots_r1.isi;
                end
                
                app.TextArea.Value = ['Selected spikes reassigned to Unit ' num2str(app.unit(2))];
                app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                app.CONFIRMCHANGESButton.Enable = true;
                app.UNDOButton.Enable = true;
                app.REASSIGNTO1Button.Enable = true;
                app.REASSIGNTO2Button.Enable = true;
                app.DELETESPIKESButton.Enable = true;
                app.MERGEALLButton.Enable = true;
%                 app.REASSIGNTO1Button.Enable = false;
%                 app.REASSIGNTO2Button.Enable = false;
%                 app.DELETESPIKESButton.Enable = false;
%                 app.MERGEALLButton.Enable = false;
            end
            
        end

        % Button pushed function: DELETESPIKESButton
        function DELETESPIKESButtonPushed(app, event)
            if app.run_pushed ~= 2
                
                app.TextArea.Value = 'Press SHOW SELECTED SPIKES button first';
                
            elseif app.run_pushed == 2
                
                app.previous_index = app.index;
                app.previous_waveforms = app.waveforms;
                app.previous_all_chan_waveforms = app.all_chan_waveforms;
                app.previous_spiketimes = app.spike_times;
                app.previous_score = app.score;
                %assignin('base','original_score',app.previous_score);
                assignin('base','index_defore_delete',app.previous_index);
                
                index_pi=find(app.pi);
                %app.waveforms = app.waveforms(:,app.po);
                for i = index_pi
                    app.waveforms(:,i) = [];
                    app.index(i) = [];
                    app.score(i,:) = [];
                    app.spike_times(i) = [];
                    app.all_chan_waveforms(:,:,i) = [];
                    
                end

                app.save_index = app.index;
                %assignin('base','index1_delete',find(app.index==0));
                %assignin('base','index2_delete',find(app.index==1));
                assignin('base','waveforms_8_modified',app.waveforms(:,find(app.save_index==0)));
                assignin('base','waveforms_35_modified',app.waveforms(:,find(app.save_index==1)));
                

                if app.number_plots == 2
                    plots_d = tjdS_plots(app.channels, app.unit, app.score, app.index, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_d.clear_all;
                    plots_d.pcas;
                    plots_d.waveforms;
                    plots_d.isis;
                elseif app.number_plots == 1
                    plots_d = tjdS_plots(app.channels, app.unit, app.score, app.index, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                    plots_d.clear_all;
                    plots_d.pca;
                    plots_d.waveform;
                    plots_d.isi;
                end
                
                app.TextArea.Value = 'Selected spikes deleted';
                app.TextArea2.Value = ['Current XBZ file: ' string(app.segment)];
                app.CONFIRMCHANGESButton.Enable = true;
                app.UNDOButton.Enable = true;
                app.REASSIGNTO1Button.Enable = true;
                app.REASSIGNTO2Button.Enable = true;
                app.DELETESPIKESButton.Enable = true;
                app.MERGEALLButton.Enable = true;
%                 app.REASSIGNTO1Button.Enable = false;
%                 app.REASSIGNTO2Button.Enable = false;
%                 app.DELETESPIKESButton.Enable = false;
%                 app.MERGEALLButton.Enable = false;
            end
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            
            app.waveforms = app.previous_waveforms;
            app.spike_times = app.previous_spiketimes;
            app.index = app.previous_index;
            app.score = app.previous_score;
            app.CONFIRMCHANGESButton.Enable = false;
            
            if app.number_plots == 2
                plots_r1 = tjdS_plots(app.channels, app.unit, app.score, app.index, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                plots_r1.clear_all;
                plots_r1.pcas;
                plots_r1.waveforms;
                plots_r1.isis;
            elseif app.number_plots == 1
                plots_r1 = tjdS_plots(app.channels, app.unit, app.score, app.index, app.waveforms, app.spike_times, app.UIAxes, app.UIAxes2, app.UIAxes4, app.UIAxes3);
                plots_r1.clear_all;
                plots_r1.pca;
                plots_r1.waveform;
                plots_r1.isi;
            end
            app.TextArea.Value = 'Changes undone';
            app.UNDOButton.Enable = false;
            app.RUNLOCKUNITSButton.Enable = true;
            
        end

        % Button pushed function: CONFIRMCHANGESButton
        function CONFIRMCHANGESButtonPushed(app, event)
            app.SHOWSELECTEDSPIKESButton.BackgroundColor = [0.96 0.96 0.96];
            app.SAVEALLCHANGESButton.Enable = true; %SAVE ALL button enabled after modifying at least one unit
            app.SHOWSELECTEDSPIKESButton.Enable = false;
            app.RUNLOCKUNITSButton.Enable = false;
            app.REASSIGNTO1Button.Enable = false;
            app.REASSIGNTO2Button.Enable = false;
            app.DELETESPIKESButton.Enable = false;
            app.MERGEALLButton.Enable = false;
            app.UNDOButton.Enable = false;
            app.CONFIRMCHANGESButton.Enable = false;
            app.PLOTButton.Enable = true;
            app.ChannelDropDown.Enable = true;
            
            index_all_save = app.save_index; %
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            
            
            if app.number_plots == 2
                
                if app.merge_all == 0
                    
                    app.TextArea.Value = 'Please wait, saving data...';
                    
                    app.index1_save = find(index_all_save == 0); %
                    app.index2_save = find(index_all_save == 1); %
                    
                    app.all_waveforms{1,app.current} = app.all_chan_waveforms(:,:,app.index1_save);
                    app.all_spike_times{1,app.current} = app.spike_times(app.index1_save);
                    
                    app.all_waveforms{2,app.current} = app.all_chan_waveforms(:,:,app.index2_save);
                    app.all_spike_times{2,app.current} = app.spike_times(app.index2_save);
                    
                    app.TextArea.Value = ['Changes to units ' app.unit_sel_1_val ' and ' app.unit_sel_2_val ' saved'];
                    assignin('base','all_waveforms_cell',app.all_waveforms);
                    assignin('base','index_after_confirm',index_all_save);
                    %assignin('base','index1_confirm',find(index_all_save==0));
                    %assignin('base','index2_confirm',find(index_all_save==1));
                    
                elseif app.merge_all == 1
                    
                    app.TextArea.Value = 'Please wait, saving data...';
                    
                    app.index1_save = find(index_all_save == 0);
                    
                    app.all_waveforms{1,app.current} = app.all_chan_waveforms(:,:,app.index1_save);
                    app.all_spike_times{1,app.current} = app.spike_times(app.index1_save);
                    
                    app.all_waveforms{2,app.current} = 0;
                    app.all_spike_times{2,app.current} = 0;
                    
                    app.TextArea.Value = ['All spikes saved in unit ' app.unit_sel_1_val];
                    assignin('base','all_waveforms_cell',app.all_waveforms);
                    
                elseif app.merge_all == 2
                    
                    app.TextArea.Value = 'Please wait, saving data...';
                    
                    app.index1_save = find(index_all_save == 1);
                    
                    app.all_waveforms{1,app.current} = 0;
                    app.all_spike_times{1,app.current} = 0;
                    
                    app.all_waveforms{2,app.current} = app.all_chan_waveforms(:,:,app.index1_save);
                    app.all_spike_times{2,app.current} = app.spike_times(app.index1_save);
                    
                    app.TextArea.Value = ['All spikes saved in unit ' app.unit_sel_2_val];
                    assignin('base','all_waveforms_cell',app.all_waveforms);
                end
                
            elseif app.number_plots == 1
                
                if app.unit_sel_1_val ~= "Empty"
                    
                    app.TextArea.Value = 'Please wait, saving data...';
                    
                    app.index1_save = index_all_save;
                    
                    app.all_waveforms{1,app.current} = app.all_chan_waveforms(:,:,app.index1_save);
                    app.all_spike_times{1,app.current} = app.spike_times(app.index1_save);
                    
                    app.TextArea.Value = ['Changes to unit ' app.unit_sel_1_val ' saved'];
                    assignin('base','all_waveforms_cell',app.all_waveforms);
                    
                elseif app.unit_sel_2_val ~= "Empty"
                    
                    app.TextArea.Value = 'Please wait, saving data...';
                    
                    app.index1_save = index_all_save;
                    
                    app.all_waveforms{2,app.current} = app.all_chan_waveforms(:,:,app.index1_save);
                    app.all_spike_times{2,app.current} = app.spike_times(app.index1_save);
                    
                    app.TextArea.Value = ['Changes to unit ' app.unit_sel_2_val ' saved'];
                    assignin('base','all_waveforms_cell',app.all_waveforms);
                    
                end
            end
        end

        % Button pushed function: SAVEALLCHANGESButton
        function SAVEALLCHANGESButtonPushed(app, event)
            
            app.SAVEALLCHANGESButton.BackgroundColor = [0.5 0.5 0.5];
            save_dir = app.save_directory;
            if not(exist(save_dir,'dir'))
                mkdir(save_dir);
            end
            
            
            if app.number_plots == 2
                
                cluster1 = app.unit(1);
                cluster2 = app.unit(2);
                
                for seg_ls = 1:size(app.all_waveforms,2)
                    app.TextArea.Value = 'Please wait, saving data...';
                    app.TextArea2.Value = 'Please wait, saving data...';
                    
                    if isempty(app.all_waveforms{1,seg_ls})==0 && isempty(app.all_waveforms{2,seg_ls})==0
                        A = app.sequence(:,seg_ls);
                        if isempty(A) == 0
                            if A(cluster1)~= 0 && A(cluster2)~= 0
                                
                                %SAVING CLUSTER 1
                                unit_file_name1 = [app.animal_name 'u00000'];
                                unit_file_name1 = [unit_file_name1(1:end-size(num2str(A(cluster1)),2)) num2str(A(cluster1)) '.mat'];
                                x = load(unit_file_name1);
                                disp(['Saving ' unit_file_name1]);
                                
                                unitname = [unit_file_name1(1:end-4) '_prev.mat'];
                                
                                if exist(unitname)==0
                                    s_unit = {};
                                    s_unit = x.s_unit;
                                    save(fullfile(save_dir,unitname),'s_unit')
                                end
                                
                                s_unit = {};
                                s_unit.id = x.s_unit.id;
                                s_unit.depth = x.s_unit.depth;
                                s_unit.templates = x.s_unit.templates;
                                s_unit.cid = x.s_unit.cid;
                                s_unit.SU = x.s_unit.SU;
                                s_unit.SU_good = x.s_unit.SU_good;
                                s_unit.SNR = x.s_unit.SNR;
                                s_unit.waveforms{1} = app.all_waveforms{1,seg_ls};
                                s_unit.spiketimes = app.all_spike_times{1,seg_ls};
                                s_unit.xbz_file_name = x.s_unit.xbz_file_name;
                                s_unit.amplitude = x.s_unit.amplitude;
                                s_unit.start_times = x.s_unit.start_times;
                                s_unit.end_times = x.s_unit.end_times;
                                %assignin('base','waveforms_8_saved',reshape(app.all_waveforms{1,seg_ls}(app.channel_sel,:,:),60,length(app.index1_save)));
                                
                                save(fullfile(save_dir,unit_file_name1),'s_unit');
                                
                                %SAVING CLUSTER 2
                                unit_file_name2 = [app.animal_name 'u00000'];
                                unit_file_name2 = [unit_file_name2(1:end-size(num2str(A(cluster2)),2)) num2str(A(cluster2)) '.mat'];
                                y = load(unit_file_name2);
                                disp(['Saving ' unit_file_name2]);
                                
                                unitname = [unit_file_name2(1:end-4) '_prev.mat'];
                                if exist(unitname)==0
                                    s_unit = {};
                                    s_unit = y.s_unit;
                                    save(fullfile(save_dir,unitname),'s_unit')
                                end
                                
                                s_unit = {};
                                s_unit.id = y.s_unit.id;
                                s_unit.depth = y.s_unit.depth;
                                s_unit.templates = y.s_unit.templates;
                                s_unit.cid = y.s_unit.cid;
                                s_unit.SU = y.s_unit.SU;
                                s_unit.SU_good = y.s_unit.SU_good;
                                s_unit.SNR = y.s_unit.SNR;
                                s_unit.waveforms{1} = app.all_waveforms{2,seg_ls};
                                s_unit.spiketimes = app.all_spike_times{2,seg_ls};
                                s_unit.xbz_file_name = y.s_unit.xbz_file_name;
                                s_unit.amplitude = y.s_unit.amplitude;
                                s_unit.start_times = y.s_unit.start_times;
                                s_unit.end_times = y.s_unit.end_times;
                                %assignin('base','waveforms_35_saved',reshape(app.all_waveforms{2,seg_ls}(app.channel_sel,:,:),60,length(app.index2_save)));
                                
                                save(fullfile(save_dir,unit_file_name2),'s_unit');
                                
                            end
                            
                        end
                    end
                end
                
            elseif app.number_plots == 1
                
                cluster1 = app.unit(1);
                
                for seg_ls = 1:size(app.all_waveforms,2)
                    app.TextArea.Value = 'Please wait, saving data...';
                    app.TextArea2.Value = 'Please wait, saving data...';
                    
                    if isempty(app.all_waveforms{1,seg_ls})==0 || isempty(app.all_waveforms{2,seg_ls})==0
                        
                        A = app.sequence(:,seg_ls);
                        if isempty(A) == 0
                            
                            if A(cluster1)~= 0
                                
                                unit_file_name = [app.animal_name 'u00000'];
                                unit_file_name = [unit_file_name(1:end-size(num2str(A(cluster1)),2)) num2str(A(cluster1)) '.mat'];
                                x = load(unit_file_name);
                                disp(['Saving ' unit_file_name]);
                                
                                unitname = [unit_file_name(1:end-4) '_prev.mat'];
                                
                                if exist(unitname)==0
                                    s_unit = {};
                                    s_unit = x.s_unit;
                                    save(fullfile(save_dir,unitname),'s_unit')
                                end
                                
                                if app.unit_sel_1_val ~= "Empty"
                                    s_unit = {};
                                    s_unit.id = x.s_unit.id;
                                    s_unit.depth = x.s_unit.depth;
                                    s_unit.templates = x.s_unit.templates;
                                    s_unit.cid = x.s_unit.cid;
                                    s_unit.SU = x.s_unit.SU;
                                    s_unit.SU_good = x.s_unit.SU_good;
                                    s_unit.SNR = x.s_unit.SNR;
                                    s_unit.waveforms{1} = app.all_waveforms{1,seg_ls};
                                    s_unit.spiketimes = app.all_spike_times{1,seg_ls};
                                    s_unit.xbz_file_name = x.s_unit.xbz_file_name;
                                    s_unit.amplitude = x.s_unit.amplitude;
                                    s_unit.start_times = x.s_unit.start_times;
                                    s_unit.end_times = x.s_unit.end_times;
                                    
                                    save(fullfile(save_dir,unit_file_name),'s_unit');
                                    
                                elseif app.unit_sel_2_val ~= "Empty"
                                    s_unit = {};
                                    s_unit.id = x.s_unit.id;
                                    s_unit.depth = x.s_unit.depth;
                                    s_unit.templates = x.s_unit.templates;
                                    s_unit.cid = x.s_unit.cid;
                                    s_unit.SU = x.s_unit.SU;
                                    s_unit.SU_good = x.s_unit.SU_good;
                                    s_unit.SNR = x.s_unit.SNR;
                                    s_unit.waveforms{1} = app.all_waveforms{2,seg_ls};
                                    s_unit.spiketimes = app.all_spike_times{2,seg_ls};
                                    s_unit.xbz_file_name = x.s_unit.xbz_file_name;
                                    s_unit.amplitude = x.s_unit.amplitude;
                                    s_unit.start_times = x.s_unit.start_times;
                                    s_unit.end_times = x.s_unit.end_times;
                                    
                                    save(fullfile(save_dir,unit_file_name),'s_unit');
                                end
                            end
                            
                        end
                    end
                end
            end
            app.TextArea.Value = 'Units saved, press Restart to select and plot different units';
            app.TextArea2.Value = 'Units saved, press Restart to select and plot different units';
            disp('All units saved');
            app.SAVEALLCHANGESButton.BackgroundColor = [0.96 0.96 0.96];
            app.SAVEALLCHANGESButton.Enable = false;
            app.RUNLOCKUNITSButton.Enable = false;
            app.PLOTButton.Enable = false;
            app.XBZFileDropDown.Enable = false;
            app.ChannelDropDown.Enable = false;
            app.UNDOButton.Enable = false;
            app.REASSIGNTO1Button.Enable = false;
            app.REASSIGNTO2Button.Enable = false;
            app.DELETESPIKESButton.Enable = false;
            app.MERGEALLButton.Enable = false;
        end

        % Button pushed function: RESTARTButton
        function RESTARTButtonPushed(app, event)
            app.SHOWSELECTEDSPIKESButton.BackgroundColor = [.96 .96 .96];
            disp('Restarting GUI..');
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            
            delete(get(app.UIAxes, 'children'));
            delete(get(app.UIAxes2, 'children'));
            delete(get(app.UIAxes3, 'children'));
            delete(get(app.UIAxes4, 'children'));
            delete(get(app.UIAxes5, 'children'));
            
            delete(legend(app.UIAxes));
            delete(legend(app.UIAxes3));
            
            app.SHOWSELECTEDSPIKESButton.BackgroundColor = [0.96 0.96 0.96];
            %app.xbz_drop_changed = 0;
            app.merge_all = 0;
            app.run_pushed = 0;
            app.number_plots = 0;
            app.unit_sel_1_val="Empty";
            app.unit_sel_2_val="Empty";
            app.channel_sel = "Empty";
            app.channel_val="Empty";
            app.UnitDropDown_2.Items = ['Empty' app.unit_numbers];
            app.UnitDropDown_3.Items = ['Empty' app.unit_numbers];
            app.XBZFileDropDown.Items = "Empty";
            app.UnitDropDown_2.Value = "Empty";
            app.UnitDropDown_3.Value = "Empty";
            app.ChannelDropDown.Items = "Empty";
            
            app.outs = [];
            app.segment = [];
            app.waveforms = [];
            app.spike_times = [];
            app.channels = [];
            app.all_chan_waveforms = [];
            app.score = [];
            app.waveforms_sel = [];
            app.index_sel = [];
            app.spike_times_sel = [];
            app.index = [];
            app.index_sel = [];
            app.save_index =[];
            app.pi = [];
            app.po = [];
            app.previous_waveforms = [];
            app.previous_all_chan_waveforms = [];
            app.previous_spiketimes = [];
            app.previous_index = [];
            app.previous_score = [];
            app.index1_save = [];
            app.index2_save = [];
            app.all_waveforms = {};
            app.all_spike_times = {};
            
            app.UnitDropDown_2.Enable = true;
            app.UnitDropDown_3.Enable = true;
            
            app.SAVEALLCHANGESButton.Enable = false;
            app.CONFIRMCHANGESButton.Enable = false;
            app.REASSIGNTO1Button.Enable = false;
            app.REASSIGNTO2Button.Enable = false;
            app.DELETESPIKESButton.Enable = false;
            app.UNDOButton.Enable = false;
            app.RUNLOCKUNITSButton.Enable = false;
            app.SHOWSELECTEDSPIKESButton.Enable = false;
            app.ChannelDropDown.Enable = false;
            app.XBZFileDropDown.Enable = false;
            app.PLOTButton.Enable = false;
            app.MERGEALLButton.Enable = false;
            
            app.TextArea.Value = 'Data cleared, ready to load data and plot again';
            app.TextArea2.Value = 'Data cleared, ready to load data and plot again';
            disp('GUI restarted');
            
        end

        % Value changed function: DistancebetweenDropDown
        function DistancebetweenDropDownValueChanged(app, event)
            value = app.DistancebetweenDropDown.Value;
            cla(app.UIAxes6);
            
            value_n = find(matches(app.DistancebetweenDropDown.Items, value));
            imagesc(app.UIAxes6, app.d_template{value_n});
            title(app.UIAxes6, 'Cluster distance');
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            value = app.UnitDropDown.Value;
            u = str2double(value);
            cla(app.UIAxes7);
            
            pool1 = [];
            pool2 = [];
            for seg_ls = 1:length(app.segment_list)-1
                pool1 = [pool1 app.d_template{seg_ls}];
                pool2 = [pool2; app.d_template{seg_ls}];
            end
            pool2 = pool2.';
            pool1 = pool1/max(max(pool1));
            pool2 = pool2/max(max(pool2));
            idx =[];
            for i = 1:length(app.segment_list)-1
                idx = [idx 1:app.N];
            end
            cmap = colormap(app.UIAxes7,'jet');
            cmap = cmap(1:2:end,:);
            
            gscatter(app.UIAxes7,pool1(u,:),pool2(u,:),idx,cmap,'o+*^x') %,'doleg','off')
            hold on
            test = sqrt(pool1(u,:).^2 + pool2(u,:).^2);
            grp2 = test(idx ~= u);
            Y1 = prctile(grp2(find(~isnan(grp2))),5); %5 percentile of group 2
            plot(app.UIAxes7,[sqrt(Y1^2+Y1^2),0],[0,sqrt(Y1^2+Y1^2)],'--')
            drawnow;
            title(app.UIAxes7,['Unit number ' num2str(u)]);
            axis(app.UIAxes7,[0 1 0 1]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.merge_clusterTab = uitab(app.TabGroup);
            app.merge_clusterTab.Title = 'merge_cluster';
            app.RUNLOCKUNITSButton = uibutton(app.merge_clusterTab, 'push');
            app.RUNLOCKUNITSButton.ButtonPushedFcn = createCallbackFcn(app, @RUNLOCKUNITSButtonPushed, true);
            app.RUNLOCKUNITSButton.Text = 'RUN & LOCK UNITS';
            app.TextArea = uitextarea(app.merge_clusterTab);
            app.TextArea2 = uitextarea(app.merge_clusterTab);
            app.SHOWSELECTEDSPIKESButton = uibutton(app.merge_clusterTab, 'push');
            app.SHOWSELECTEDSPIKESButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWSELECTEDSPIKESButtonPushed, true);
            app.SHOWSELECTEDSPIKESButton.Text = 'SHOW SELECTED SPIKES';
            app.RESTARTButton = uibutton(app.merge_clusterTab, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTARTButtonPushed, true);
            app.RESTARTButton.Text = 'RESTART';
            app.REASSIGNTO1Button = uibutton(app.merge_clusterTab, 'push');
            app.REASSIGNTO1Button.ButtonPushedFcn = createCallbackFcn(app, @REASSIGNTO1ButtonPushed, true);
            app.REASSIGNTO1Button.Text = 'REASSIGN TO 1';
            app.REASSIGNTO2Button = uibutton(app.merge_clusterTab, 'push');
            app.REASSIGNTO2Button.ButtonPushedFcn = createCallbackFcn(app, @REASSIGNTO2ButtonPushed, true);
            app.REASSIGNTO2Button.Text = 'REASSIGN TO 2';
            app.DELETESPIKESButton = uibutton(app.merge_clusterTab, 'push');
            app.DELETESPIKESButton.ButtonPushedFcn = createCallbackFcn(app, @DELETESPIKESButtonPushed, true);
            app.DELETESPIKESButton.Text = 'DELETE SPIKES';
            app.SAVEALLCHANGESButton = uibutton(app.merge_clusterTab, 'push');
            app.SAVEALLCHANGESButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEALLCHANGESButtonPushed, true);
            app.SAVEALLCHANGESButton.Text = 'SAVE ALL CHANGES';
            app.Image = uiimage(app.merge_clusterTab);
            app.Image.ImageSource = 'Logo.jpg';
            app.UnitDropDown_3Label = uilabel(app.merge_clusterTab);
            app.UnitDropDown_3Label.Text = 'Unit';
            app.UnitDropDown_3 = uidropdown(app.merge_clusterTab);
            app.UnitDropDown_3.ValueChangedFcn = createCallbackFcn(app, @UnitDropDown_3ValueChanged, true);
            app.UnitDropDown_2Label = uilabel(app.merge_clusterTab);
            app.UnitDropDown_2Label.Text = 'Unit';
            app.UnitDropDown_2 = uidropdown(app.merge_clusterTab);
            app.UnitDropDown_2.ValueChangedFcn = createCallbackFcn(app, @UnitDropDown_2ValueChanged, true);
            app.PLOTButton = uibutton(app.merge_clusterTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.ChannelDropDownLabel = uilabel(app.merge_clusterTab);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.merge_clusterTab);
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.XBZFileDropDownLabel = uilabel(app.merge_clusterTab);
            app.XBZFileDropDownLabel.Text = 'XBZ File';
            app.XBZFileDropDown = uidropdown(app.merge_clusterTab);
            app.XBZFileDropDown.ValueChangedFcn = createCallbackFcn(app, @XBZFileDropDownValueChanged, true);
            app.CONFIRMCHANGESButton = uibutton(app.merge_clusterTab, 'push');
            app.CONFIRMCHANGESButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIRMCHANGESButtonPushed, true);
            app.CONFIRMCHANGESButton.Text = 'CONFIRM CHANGES';
            app.UNDOButton = uibutton(app.merge_clusterTab, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Text = 'UNDO';
            app.MERGEALLButton = uibutton(app.merge_clusterTab, 'push');
            app.MERGEALLButton.ButtonPushedFcn = createCallbackFcn(app, @MERGEALLButtonPushed, true);
            app.MERGEALLButton.Text = 'MERGE ALL';
            app.UIAxes = uiaxes(app.merge_clusterTab);
            title(app.UIAxes, 'PCA')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.merge_clusterTab);
            title(app.UIAxes2, 'Waveform')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.merge_clusterTab);
            title(app.UIAxes3, 'ISI')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.merge_clusterTab);
            title(app.UIAxes4, 'Waveform')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.merge_clusterTab);
            title(app.UIAxes5, 'Selected Spikes')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.CdistanceTab = uitab(app.TabGroup);
            app.CdistanceTab.Title = 'Cdistance';
            app.TextArea3 = uitextarea(app.CdistanceTab);
            app.DistancebetweenDropDownLabel = uilabel(app.CdistanceTab);
            app.DistancebetweenDropDownLabel.Text = 'Distance between';
            app.DistancebetweenDropDown = uidropdown(app.CdistanceTab);
            app.DistancebetweenDropDown.ValueChangedFcn = createCallbackFcn(app, @DistancebetweenDropDownValueChanged, true);
            app.UnitDropDownLabel = uilabel(app.CdistanceTab);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.CdistanceTab);
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UIAxes6 = uiaxes(app.CdistanceTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.CdistanceTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_TJD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end