classdef OE_xb_2Dstim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Plot1                  matlab.ui.control.UIAxes
        Plot2                  matlab.ui.control.UIAxes
        RasterPlot             matlab.ui.control.UIAxes
        OpenFileButton         matlab.ui.control.Button
        SessionEditFieldLabel  matlab.ui.control.Label
        SessionEditField       matlab.ui.control.EditField
        MonkeyEditFieldLabel   matlab.ui.control.Label
        MonkeyEditField        matlab.ui.control.EditField
        TextAreaLabel          matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        OpenFileButton_2       matlab.ui.control.Button
        M_fileEditFieldLabel   matlab.ui.control.Label
        M_fileEditField        matlab.ui.control.EditField
        raw_idEditFieldLabel   matlab.ui.control.Label
        raw_idEditField        matlab.ui.control.EditField
        spks_idEditFieldLabel  matlab.ui.control.Label
        spks_idEditField       matlab.ui.control.EditField
        RunButton              matlab.ui.control.Button
        Button_1               matlab.ui.control.StateButton
        Button_2               matlab.ui.control.StateButton
        Button_3               matlab.ui.control.StateButton
        Button_4               matlab.ui.control.StateButton
        Button_5               matlab.ui.control.StateButton
        Button_6               matlab.ui.control.StateButton
        Button_7               matlab.ui.control.StateButton
        Button_8               matlab.ui.control.StateButton
        Button_9               matlab.ui.control.StateButton
        Button_10              matlab.ui.control.StateButton
        Button_11              matlab.ui.control.StateButton
        Button_12              matlab.ui.control.StateButton
        Button_13              matlab.ui.control.StateButton
        Button_14              matlab.ui.control.StateButton
        Button_15              matlab.ui.control.StateButton
        Button_16              matlab.ui.control.StateButton
        Button_17              matlab.ui.control.StateButton
        Button_18              matlab.ui.control.StateButton
        Button_19              matlab.ui.control.StateButton
        Button_20              matlab.ui.control.StateButton
        Button_21              matlab.ui.control.StateButton
        Button_22              matlab.ui.control.StateButton
        Button_23              matlab.ui.control.StateButton
        Button_24              matlab.ui.control.StateButton
        Button_25              matlab.ui.control.StateButton
        Button_26              matlab.ui.control.StateButton
        Button_27              matlab.ui.control.StateButton
        Button_28              matlab.ui.control.StateButton
        Button_29              matlab.ui.control.StateButton
        Button_30              matlab.ui.control.StateButton
        Button_31              matlab.ui.control.StateButton
        Button_32              matlab.ui.control.StateButton
        Button_33              matlab.ui.control.StateButton
        Button_34              matlab.ui.control.StateButton
        Button_35              matlab.ui.control.StateButton
        Button_36              matlab.ui.control.StateButton
        Button_37              matlab.ui.control.StateButton
        Button_38              matlab.ui.control.StateButton
        Button_39              matlab.ui.control.StateButton
        Button_40              matlab.ui.control.StateButton
        Button_41              matlab.ui.control.StateButton
        Button_42              matlab.ui.control.StateButton
        Button_43              matlab.ui.control.StateButton
        Button_44              matlab.ui.control.StateButton
        Button_45              matlab.ui.control.StateButton
        Button_46              matlab.ui.control.StateButton
        Button_47              matlab.ui.control.StateButton
        Button_48              matlab.ui.control.StateButton
        Button_49              matlab.ui.control.StateButton
        Button_50              matlab.ui.control.StateButton
        Button_51              matlab.ui.control.StateButton
        Button_52              matlab.ui.control.StateButton
        Button_53              matlab.ui.control.StateButton
        Button_54              matlab.ui.control.StateButton
        Button_55              matlab.ui.control.StateButton
        Button_56              matlab.ui.control.StateButton
        Button_57              matlab.ui.control.StateButton
        Button_58              matlab.ui.control.StateButton
        Button_59              matlab.ui.control.StateButton
        Button_60              matlab.ui.control.StateButton
        Button_61              matlab.ui.control.StateButton
        Button_62              matlab.ui.control.StateButton
        Button_63              matlab.ui.control.StateButton
        Button_64              matlab.ui.control.StateButton
        WaveformLabel          matlab.ui.control.Label
        TuningLabel            matlab.ui.control.Label
        StimEditFieldLabel     matlab.ui.control.Label
        StimEditField          matlab.ui.control.EditField
        DropDown_x             matlab.ui.control.DropDown
        DropDown_y             matlab.ui.control.DropDown
        PlotButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        MKname = []; % monkey id
        x = {}; % xb3file
        raw_id = [];
        spks_id = [];
        out = {};
        Ch_selec = [];
        Ch_buttons = {};
        stim_info = {};
    end


    methods (Access = private)
        
        function app = index_stim(app)
            switch lower(app.StimEditField.Value)
                case 'trill1'
                    app.stim_info.idx = [10,22];
                case 'trill2'
                case 'twitter'
                case 'phee'
                case 'fra'
                    app.stim_info.idx = [8,3];
                    
            end
            app.stim_info.x_list = unique(app.x.stimulus_ch1(:,app.stim_info.idx(1))); % usually CF
            app.stim_info.y_list = unique(app.x.stimulus_ch1(:,app.stim_info.idx(2)));
            
            app.DropDown_x.Items = string([0;app.stim_info.x_list]);
            app.DropDown_x.ItemsData = [0;app.stim_info.x_list];
            app.DropDown_y.Items = string([0; app.stim_info.y_list]);
            app.DropDown_y.ItemsData = [0; app.stim_info.y_list];
            
        end
        
        
        function plot_figures(app,plot_nb,type)
            switch type
                case 'Waveform'
                    if isempty(app.Ch_selec) ==1
                        app.TextArea.Value{length(app.TextArea.Value)+1} = 'No channels selected';
                    elseif length(app.Ch_selec) == 1
                        hold(plot_nb,'off')
                        Nb = min([100,size(app.out.spikes{app.Ch_selec},1)]);
                        sample_n = randsample(size(app.out.spikes{app.Ch_selec},1),Nb) ;
                        for n = 1:Nb
                            plot(plot_nb,app.out.spikes{app.Ch_selec}(sample_n(n),:))
                            hold(plot_nb,'on')
                        end
                        plot(plot_nb,mean(app.out.spikes{app.Ch_selec},1),'Linewidth',2,'Color','k');
                        drawnow
                    else
                        hold(plot_nb,'off')
                        for ch = app.Ch_selec
                            plot(plot_nb,mean(app.out.spikes{ch},1),'Linewidth',2);
                            hold(plot_nb,'on')
                        end
                    end
                case 'Tuning'
                    if isempty(app.Ch_selec) ==1
                        app.TextArea.Value{length(app.TextArea.Value)+1} = 'No channels selected';
                    elseif length(app.Ch_selec) == 1
                        M = mean(app.out.rate.rate_stim{app.Ch_selec},2);
                        M = reshape(M,[length(app.stim_info.x_list),length(app.stim_info.y_list)]);
                        imagesc(app.Plot2,app.stim_info.y_list,app.stim_info.x_list,M);
                    end
                    
                case 'RasterPlot'
                    if isempty(app.Ch_selec) ==1
                        app.TextArea.Value{length(app.TextArea.Value)+1} = 'No channels selected';
                    elseif length(app.Ch_selec) == 1
                        [new_vec, new_vec2] = select_stim(app);
                        
                        new_raster.spikes = app.out.raster.spikes{app.Ch_selec}(new_vec2);
                        new_raster.stim1 = app.out.raster.stim{app.Ch_selec}(new_vec2);
                        new_raster.rep = app.out.raster.rep{app.Ch_selec}(new_vec2);
                        new_raster.stim2 = zeros(1,length(new_raster.stim1));
                        for stt = 1:length(new_raster.stim1)
                            new_raster.stim2(1,stt) = find(unique(new_raster.stim1) == new_raster.stim1(1,stt));
                        end
                        
                        
                        for st = 1: length(new_vec)
                            rectangle(app.RasterPlot,'Position',[0 app.out.nreps*(st-1),app.out.StimDur(new_vec(st)) app.out.nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                        end
                        hold(app.RasterPlot,'on')
                        plot(app.RasterPlot,new_raster.spikes,app.out.nreps*(new_raster.stim2-1)+new_raster.rep,'k.','MarkerSize',9);
                        xlabel(app.RasterPlot,'time (s)')
                        ylabel(app.RasterPlot,'kHz')
                        
                        axis(app.RasterPlot,[-app.out.PreStim max(app.out.StimDur) + app.out.PostStim 0 app.out.nreps*length(new_vec)+1])
                        if app.DropDown_x.Value ~= 0 % if the 1st parameter was set the 2nd parameter varies   
                            yticks(app.RasterPlot,[1:app.out.nreps:app.out.nreps*length(new_vec)]+floor(app.out.nreps/2)) ;
                            yticklabels(app.RasterPlot, num2str(app.stim_info.y_list))
                            ylabel(app.RasterPlot,app.x.stimulus_tags_ch1{app.stim_info.idx});

                            
                        elseif app.DropDown_y.Value ~= 0
                            yticks(app.RasterPlot,[1:app.out.nreps:app.out.nreps*length(new_vec)]+floor(app.out.nreps/2)) ;
                            yticklabels(app.RasterPlot, num2str(app.stim_info.x_list))
                            ylabel(app.RasterPlot,app.x.stimulus_tags_ch1{app.stim_info.idx});
                        end    
                    end
                
            end
        end


        function [new_vec, new_vec2] = select_stim(app)
            
            if isempty(app.DropDown_x.Value) && isempty(app.DropDown_y.Value)
                new_vec2 = [1:length(app.out.raster.stim{app.Ch_selec})].';
                new_vec = app.x.stimulus_ch1(:,1);
            elseif app.DropDown_x.Value == 0 && app.DropDown_y.Value == 0
                new_vec2 = [1:length(app.out.raster.stim{app.Ch_selec})].';
                new_vec = app.x.stimulus_ch1(:,1);
            elseif app.DropDown_x.Value ~= 0
                new_vec = find(app.x.stimulus_ch1(:,app.stim_info.idx(1)) == app.DropDown_x.Value).';
                new_vec2 = [];
                for v = new_vec
                    new_vec2 = [new_vec2, find(app.out.raster.stim{app.Ch_selec} == v)];
                end
            elseif app.DropDown_y.Value ~= 0
                new_vec = find(app.x.stimulus_ch1(:,app.stim_info.idx(2)) == app.DropDown_y.Value).';
                new_vec2 = [];
                for v = new_vec
                    new_vec2 = [new_vec2, find(app.out.raster.stim{app.Ch_selec} == v)];
                end
            end
                            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function WaveformButtonPushed(app, event)
            %              title(['cluster nb ' num2str(app.N)])
            %                 for n = 1:min([100,size(app.spikes{app.N},1)])
            %                     plot(app.Plot1,app.spikes{n}(n,:))
            %                     hold(app.Plot1,'on')
            %                 end
            %             app.N = app.N + 1;
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            dirname = uigetdir(fullfile('D:\Data\Experiments_John', filesep, app.MKname));
            app.SessionEditField.Value = dirname(end-19:end);
            filelist1 = dir(fullfile(dirname, filesep,'*.spikes'));
            filelist2 = dir(fullfile(dirname,filesep,'*.continuous'));
            app.spks_id = filelist1(1).name(4:6);
            app.spks_idEditField.Value = app.spks_id;
            app.raw_id = filelist2(1).name(1:3);
            app.raw_idEditField.Value = app.raw_id;
        end

        % Value changed function: MonkeyEditField
        function MonkeyEditFieldValueChanged(app, event)
            app.MKname = app.MonkeyEditField.Value;
            
        end

        % Button pushed function: OpenFileButton_2
        function OpenFileButton_2Pushed(app, event)
            addpath(fullfile('D:\Data\Experiments_John',app.MKname))
            app.TextArea.Value{length(app.TextArea.Value)+1} = 'loading xb3 file...';
            mfile =  uigetfile(fullfile('D:\Data\Experiments_John', filesep, app.MKname));
            app.TextArea.Value{length(app.TextArea.Value)+1} = mfile;
            app.M_fileEditField.Value = mfile(1:end-2);
            app.x = eval(mfile(1:end-2));
            app.TextArea.Value{length(app.TextArea.Value)+1} = 'done';
            app = index_stim(app);
        end

        % Value changed function: raw_idEditField
        function raw_idEditFieldValueChanged(app, event)
            app.raw_id= app.raw_idEditField.Value;
        end

        % Value changed function: spks_idEditField
        function spks_idEditFieldValueChanged(app, event)
            app.spks_id= app.spks_idEditField.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            fpath = fullfile('D:\Data\Experiments_John', filesep, app.MKname,app.SessionEditField.Value);
            addpath('C:\Users\skoehler\Documents\GitHub\Kilosort-Wanglab\Analysis\OE_xb_spk');
            app.out = Spike_Analysis_app(fpath,app.x,app.raw_id,app.spks_id);
            app.Ch_buttons = {app.Button_1, app.Button_2, app.Button_3, app.Button_4, app.Button_5, app.Button_6, app.Button_7, app.Button_8, app.Button_9, app.Button_10, ...
                app.Button_11, app.Button_12, app.Button_13, app.Button_14, app.Button_15, app.Button_16, app.Button_17, app.Button_18, app.Button_19, app.Button_20, ...
                app.Button_21, app.Button_22, app.Button_23, app.Button_24, app.Button_25, app.Button_26, app.Button_27, app.Button_28, app.Button_29, app.Button_30, ...
                app.Button_31, app.Button_32, app.Button_33, app.Button_34, app.Button_35, app.Button_36, app.Button_37, app.Button_38, app.Button_39, app.Button_40, ...
                app.Button_41, app.Button_42, app.Button_43, app.Button_44, app.Button_45, app.Button_46, app.Button_47, app.Button_48, app.Button_49, app.Button_50, ...
                app.Button_51, app.Button_52, app.Button_53, app.Button_54, app.Button_55, app.Button_56, app.Button_57, app.Button_58, app.Button_59, app.Button_60, ...
                app.Button_61, app.Button_62, app.Button_63, app.Button_64};
            app.Ch_selec = [];

            for n = 1:64
                if isempty(app.out.spikes{n}) || size(app.out.spikes{n},1) <30
                    app.Ch_buttons{n}.BackgroundColor = [1.00,0,0];
                else
                    app.Ch_buttons{n}.BackgroundColor = [1.00,1.00,1.00];
                end
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.Ch_selec = [];
            
            for n = 1:64
                var_name = ['app.Button_',int2str(n),'.Value'];
                if eval(var_name) ==1 %button on
                    app.Ch_selec = [app.Ch_selec n];
                    app.Ch_selec = unique(app.Ch_selec);
                end
            end
            
            plot_figures(app,app.Plot1,'Waveform')
            drawnow
            plot_figures(app,app.Plot2,'Tuning')
            drawnow
            plot_figures(app,[],'RasterPlot')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Plot1 = uiaxes(app.UIFigure);
            title(app.Plot1, '')
            xlabel(app.Plot1, 'sample points')
            ylabel(app.Plot1, 'mV')
            app.Plot2 = uiaxes(app.UIFigure);
            title(app.Plot2, '')
            xlabel(app.Plot2, 'X')
            ylabel(app.Plot2, 'Y')
            app.RasterPlot = uiaxes(app.UIFigure);
            title(app.RasterPlot, 'Raster Plot')
            xlabel(app.RasterPlot, 'X')
            ylabel(app.RasterPlot, 'Y')
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.SessionEditFieldLabel = uilabel(app.UIFigure);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.UIFigure, 'text');
            app.MonkeyEditFieldLabel = uilabel(app.UIFigure);
            app.MonkeyEditFieldLabel.Text = 'Monkey';
            app.MonkeyEditField = uieditfield(app.UIFigure, 'text');
            app.MonkeyEditField.ValueChangedFcn = createCallbackFcn(app, @MonkeyEditFieldValueChanged, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.OpenFileButton_2 = uibutton(app.UIFigure, 'push');
            app.OpenFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButton_2Pushed, true);
            app.OpenFileButton_2.Text = 'Open File';
            app.M_fileEditFieldLabel = uilabel(app.UIFigure);
            app.M_fileEditFieldLabel.Text = 'M_file';
            app.M_fileEditField = uieditfield(app.UIFigure, 'text');
            app.raw_idEditFieldLabel = uilabel(app.UIFigure);
            app.raw_idEditFieldLabel.Text = 'raw_id';
            app.raw_idEditField = uieditfield(app.UIFigure, 'text');
            app.raw_idEditField.ValueChangedFcn = createCallbackFcn(app, @raw_idEditFieldValueChanged, true);
            app.spks_idEditFieldLabel = uilabel(app.UIFigure);
            app.spks_idEditFieldLabel.Text = 'spks_id';
            app.spks_idEditField = uieditfield(app.UIFigure, 'text');
            app.spks_idEditField.ValueChangedFcn = createCallbackFcn(app, @spks_idEditFieldValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'state');
            app.Button_10.Text = '10';
            app.Button_11 = uibutton(app.UIFigure, 'state');
            app.Button_11.Text = '11';
            app.Button_12 = uibutton(app.UIFigure, 'state');
            app.Button_12.Text = '12';
            app.Button_13 = uibutton(app.UIFigure, 'state');
            app.Button_13.Text = '13';
            app.Button_14 = uibutton(app.UIFigure, 'state');
            app.Button_14.Text = '14';
            app.Button_15 = uibutton(app.UIFigure, 'state');
            app.Button_15.Text = '15';
            app.Button_16 = uibutton(app.UIFigure, 'state');
            app.Button_16.Text = '16';
            app.Button_17 = uibutton(app.UIFigure, 'state');
            app.Button_17.Text = '17';
            app.Button_18 = uibutton(app.UIFigure, 'state');
            app.Button_18.Text = '18';
            app.Button_19 = uibutton(app.UIFigure, 'state');
            app.Button_19.Text = '19';
            app.Button_20 = uibutton(app.UIFigure, 'state');
            app.Button_20.Text = '20';
            app.Button_21 = uibutton(app.UIFigure, 'state');
            app.Button_21.Text = '21';
            app.Button_22 = uibutton(app.UIFigure, 'state');
            app.Button_22.Text = '22';
            app.Button_23 = uibutton(app.UIFigure, 'state');
            app.Button_23.Text = '23';
            app.Button_24 = uibutton(app.UIFigure, 'state');
            app.Button_24.Text = '24';
            app.Button_25 = uibutton(app.UIFigure, 'state');
            app.Button_25.Text = '25';
            app.Button_26 = uibutton(app.UIFigure, 'state');
            app.Button_26.Text = '26';
            app.Button_27 = uibutton(app.UIFigure, 'state');
            app.Button_27.Text = '27';
            app.Button_28 = uibutton(app.UIFigure, 'state');
            app.Button_28.Text = '28';
            app.Button_29 = uibutton(app.UIFigure, 'state');
            app.Button_29.Text = '29';
            app.Button_30 = uibutton(app.UIFigure, 'state');
            app.Button_30.Text = '30';
            app.Button_31 = uibutton(app.UIFigure, 'state');
            app.Button_31.Text = '31';
            app.Button_32 = uibutton(app.UIFigure, 'state');
            app.Button_32.Text = '32';
            app.Button_33 = uibutton(app.UIFigure, 'state');
            app.Button_33.Text = '33';
            app.Button_34 = uibutton(app.UIFigure, 'state');
            app.Button_34.Text = '34';
            app.Button_35 = uibutton(app.UIFigure, 'state');
            app.Button_35.Text = '35';
            app.Button_36 = uibutton(app.UIFigure, 'state');
            app.Button_36.Text = '36';
            app.Button_37 = uibutton(app.UIFigure, 'state');
            app.Button_37.Text = '37';
            app.Button_38 = uibutton(app.UIFigure, 'state');
            app.Button_38.Text = '38';
            app.Button_39 = uibutton(app.UIFigure, 'state');
            app.Button_39.Text = '39';
            app.Button_40 = uibutton(app.UIFigure, 'state');
            app.Button_40.Text = '40';
            app.Button_41 = uibutton(app.UIFigure, 'state');
            app.Button_41.Text = '41';
            app.Button_42 = uibutton(app.UIFigure, 'state');
            app.Button_42.Text = '42';
            app.Button_43 = uibutton(app.UIFigure, 'state');
            app.Button_43.Text = '43';
            app.Button_44 = uibutton(app.UIFigure, 'state');
            app.Button_44.Text = '44';
            app.Button_45 = uibutton(app.UIFigure, 'state');
            app.Button_45.Text = '45';
            app.Button_46 = uibutton(app.UIFigure, 'state');
            app.Button_46.Text = '46';
            app.Button_47 = uibutton(app.UIFigure, 'state');
            app.Button_47.Text = '47';
            app.Button_48 = uibutton(app.UIFigure, 'state');
            app.Button_48.Text = '48';
            app.Button_49 = uibutton(app.UIFigure, 'state');
            app.Button_49.Text = '49';
            app.Button_50 = uibutton(app.UIFigure, 'state');
            app.Button_50.Text = '50';
            app.Button_51 = uibutton(app.UIFigure, 'state');
            app.Button_51.Text = '51';
            app.Button_52 = uibutton(app.UIFigure, 'state');
            app.Button_52.Text = '52';
            app.Button_53 = uibutton(app.UIFigure, 'state');
            app.Button_53.Text = '53';
            app.Button_54 = uibutton(app.UIFigure, 'state');
            app.Button_54.Text = '54';
            app.Button_55 = uibutton(app.UIFigure, 'state');
            app.Button_55.Text = '55';
            app.Button_56 = uibutton(app.UIFigure, 'state');
            app.Button_56.Text = '56';
            app.Button_57 = uibutton(app.UIFigure, 'state');
            app.Button_57.Text = '57';
            app.Button_58 = uibutton(app.UIFigure, 'state');
            app.Button_58.Text = '58';
            app.Button_59 = uibutton(app.UIFigure, 'state');
            app.Button_59.Text = '59';
            app.Button_60 = uibutton(app.UIFigure, 'state');
            app.Button_60.Text = '60';
            app.Button_61 = uibutton(app.UIFigure, 'state');
            app.Button_61.Text = '61';
            app.Button_62 = uibutton(app.UIFigure, 'state');
            app.Button_62.Text = '62';
            app.Button_63 = uibutton(app.UIFigure, 'state');
            app.Button_63.Text = '63';
            app.Button_64 = uibutton(app.UIFigure, 'state');
            app.Button_64.Text = '64';
            app.WaveformLabel = uilabel(app.UIFigure);
            app.WaveformLabel.Text = 'Waveform';
            app.TuningLabel = uilabel(app.UIFigure);
            app.TuningLabel.Text = 'Tuning';
            app.StimEditFieldLabel = uilabel(app.UIFigure);
            app.StimEditFieldLabel.Text = 'Stim';
            app.StimEditField = uieditfield(app.UIFigure, 'text');
            app.DropDown_x = uidropdown(app.UIFigure);
            app.DropDown_x.Items = {'0'};
            app.DropDown_x.Value = '0';
            app.DropDown_y = uidropdown(app.UIFigure);
            app.DropDown_y.Items = {'0'};
            app.DropDown_y.Value = '0';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OE_xb_2Dstim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end