classdef GUI_tjx_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SearchpathEditFieldLabel      matlab.ui.control.Label
        SearchpathEditField           matlab.ui.control.EditField
        SearchButtonPath              matlab.ui.control.Button
        PCNameEditFieldLabel          matlab.ui.control.Label
        PCNameEditField               matlab.ui.control.EditField
        AnimalNameEditFieldLabel      matlab.ui.control.Label
        AnimalNameEditField           matlab.ui.control.EditField
        SessionIDEditFieldLabel       matlab.ui.control.Label
        SessionIDEditField            matlab.ui.control.NumericEditField
        ParametersfileEditFieldLabel  matlab.ui.control.Label
        ParametersfileEditField       matlab.ui.control.EditField
        SearchButtonXFile             matlab.ui.control.Button
        RUNButton                     matlab.ui.control.Button
        CLUSTERLabel                  matlab.ui.control.Label
        CLUSTERDropDown               matlab.ui.control.DropDown
        CLUSTERLabel_2                matlab.ui.control.Label
        CLUSTERDropDown_2             matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        TextArea                      matlab.ui.control.TextArea
        PLOTButton                    matlab.ui.control.Button
        SAVEButton                    matlab.ui.control.Button
        UIAxes7                       matlab.ui.control.UIAxes
        UIAxes8_1                     matlab.ui.control.UIAxes
        UIAxes8_2                     matlab.ui.control.UIAxes
        UIAxes8_3                     matlab.ui.control.UIAxes
        UIAxes8_4                     matlab.ui.control.UIAxes
        UIAxes8_5                     matlab.ui.control.UIAxes
        UIAxes8_6                     matlab.ui.control.UIAxes
        UIAxes8_7                     matlab.ui.control.UIAxes
        UIAxes8_8                     matlab.ui.control.UIAxes
        UIAxes8_9                     matlab.ui.control.UIAxes
        UIAxes8_10                    matlab.ui.control.UIAxes
        UIAxes8_11                    matlab.ui.control.UIAxes
        UIAxes8_12                    matlab.ui.control.UIAxes
        UIAxes8_13                    matlab.ui.control.UIAxes
        UIAxes8_14                    matlab.ui.control.UIAxes
        UIAxes8_15                    matlab.ui.control.UIAxes
        UIAxes8_16                    matlab.ui.control.UIAxes
        UIAxes8_17                    matlab.ui.control.UIAxes
        UIAxes8_18                    matlab.ui.control.UIAxes
        UIAxes8_19                    matlab.ui.control.UIAxes
        UIAxes8_20                    matlab.ui.control.UIAxes
        UIAxes8_21                    matlab.ui.control.UIAxes
        UIAxes8_22                    matlab.ui.control.UIAxes
        UIAxes8_23                    matlab.ui.control.UIAxes
        UIAxes8_24                    matlab.ui.control.UIAxes
        UIAxes8_25                    matlab.ui.control.UIAxes
        UIAxes8_26                    matlab.ui.control.UIAxes
        UIAxes8_27                    matlab.ui.control.UIAxes
        UIAxes8_28                    matlab.ui.control.UIAxes
        UIAxes8_29                    matlab.ui.control.UIAxes
        UIAxes8_30                    matlab.ui.control.UIAxes
        UIAxes8_31                    matlab.ui.control.UIAxes
        UIAxes8_32                    matlab.ui.control.UIAxes
        UIAxes8_33                    matlab.ui.control.UIAxes
        UIAxes8_34                    matlab.ui.control.UIAxes
        UIAxes8_35                    matlab.ui.control.UIAxes
        UIAxes8_36                    matlab.ui.control.UIAxes
        UIAxes8_37                    matlab.ui.control.UIAxes
        UIAxes8_38                    matlab.ui.control.UIAxes
        UIAxes8_39                    matlab.ui.control.UIAxes
        UIAxes8_40                    matlab.ui.control.UIAxes
        UIAxes8_41                    matlab.ui.control.UIAxes
        UIAxes8_42                    matlab.ui.control.UIAxes
        UIAxes8_43                    matlab.ui.control.UIAxes
        UIAxes8_44                    matlab.ui.control.UIAxes
        UIAxes8_45                    matlab.ui.control.UIAxes
        UIAxes8_46                    matlab.ui.control.UIAxes
        UIAxes8_47                    matlab.ui.control.UIAxes
        UIAxes8_48                    matlab.ui.control.UIAxes
        UIAxes8_49                    matlab.ui.control.UIAxes
        UIAxes8_50                    matlab.ui.control.UIAxes
        UIAxes8_51                    matlab.ui.control.UIAxes
        UIAxes8_52                    matlab.ui.control.UIAxes
        UIAxes8_53                    matlab.ui.control.UIAxes
        UIAxes8_54                    matlab.ui.control.UIAxes
        UIAxes8_55                    matlab.ui.control.UIAxes
        UIAxes8_56                    matlab.ui.control.UIAxes
        UIAxes8_57                    matlab.ui.control.UIAxes
        UIAxes8_58                    matlab.ui.control.UIAxes
        UIAxes8_59                    matlab.ui.control.UIAxes
        UIAxes8_60                    matlab.ui.control.UIAxes
        UIAxes8_61                    matlab.ui.control.UIAxes
        UIAxes8_62                    matlab.ui.control.UIAxes
        UIAxes8_63                    matlab.ui.control.UIAxes
        UIAxes8_64                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ParxFile;
        SearchPath;
        out;
        clust;
        value = "Empty";
        value2 = "Empty";
        valor;
        valor2;
        ids;
        ids2;
        animal_name;
        
    end
    

    methods (Access = private)

        % Button pushed function: SearchButtonPath
        function SearchButtonPathPushed(app, event)
            app.SearchPath = uigetdir('D:\Data\Experiments'); %This is the search path for fpath2
            app.SearchpathEditField.Value = app.SearchPath; %This just shows the path in the GUI
            
        end

        % Button pushed function: SearchButtonXFile
        function SearchButtonXFilePushed(app, event)
            [app.ParxFile,PathXfile] = uigetfile; %Getting the file and its path
            app.ParametersfileEditField.Value = fullfile(PathXfile,app.ParxFile); %Showing the path to the parameters file
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            tic;
            app.TextArea.Value = 'Loading, please wait...';
            
            fpath2 = app.SearchPath;
            PC_name = app.PCNameEditField.Value;
            app.animal_name = app.AnimalNameEditField.Value;
            Session_id = app.SessionIDEditField.Value;
            PF = erase(app.ParxFile, '.m');
            ParamsFile = str2func(PF);
            app.out = tjxS(ParamsFile,PC_name, app.animal_name, fpath2, Session_id);
            app.out.initialize;
            pause(0.1);
            app.out.load_data_OE;
            pause(0.1);
            app.out.extract_units;
            pause(0.1);
            app.out.Single_units;
            app.clust=string(app.out.clusters);
            app.CLUSTERDropDown.Items=['Empty',app.clust];
            app.CLUSTERDropDown_2.Items=['Empty',app.clust];
            
            time_step = [1:60]/app.out.params.fs*1e3;
            A = app.out.good_ch;
            chmap=[27 21 32 3 25 19 30 5 23 24 17 7 20 26 29 9 22 28 31 11 16 1 13 15 18 14 12 10 8 6 4 2 64 62 60 58 ...
                56 54 52 48 49 51 53 55 57 59 61 63 38 40 42 45 43 33 35 47 36 34 50 44 46 41 39 37];
            
            ch=[app.UIAxes8_1 app.UIAxes8_2 app.UIAxes8_3 app.UIAxes8_4 app.UIAxes8_5 app.UIAxes8_6 app.UIAxes8_7 app.UIAxes8_8 app.UIAxes8_9 app.UIAxes8_10 app.UIAxes8_11 app.UIAxes8_12 app.UIAxes8_13 app.UIAxes8_14 app.UIAxes8_15 app.UIAxes8_16 ...
                app.UIAxes8_17 app.UIAxes8_18 app.UIAxes8_19 app.UIAxes8_20 app.UIAxes8_21 app.UIAxes8_22 app.UIAxes8_23 app.UIAxes8_24 app.UIAxes8_25 app.UIAxes8_26 app.UIAxes8_27 app.UIAxes8_28 app.UIAxes8_29 app.UIAxes8_30 app.UIAxes8_31 app.UIAxes8_32 app.UIAxes8_33 ...
                app.UIAxes8_34 app.UIAxes8_35 app.UIAxes8_36 app.UIAxes8_37 app.UIAxes8_38 app.UIAxes8_39 app.UIAxes8_40 app.UIAxes8_41 app.UIAxes8_42 app.UIAxes8_43 app.UIAxes8_44 app.UIAxes8_45 app.UIAxes8_46 app.UIAxes8_47 app.UIAxes8_48 app.UIAxes8_49 app.UIAxes8_50 ...
                app.UIAxes8_51 app.UIAxes8_52 app.UIAxes8_53 app.UIAxes8_54 app.UIAxes8_55 app.UIAxes8_56 app.UIAxes8_57 app.UIAxes8_58 app.UIAxes8_59 app.UIAxes8_60 app.UIAxes8_61 app.UIAxes8_62 app.UIAxes8_63 app.UIAxes8_64];

            if ismember(A, chmap) 
                [~,Locb]=ismember(A, chmap);
                
                for i=1:length(A)
                    id = app.out.idds(i);
                    x=ch(Locb(i));
                    plot(x,time_step,app.out.waveforms.mean{id},'Linewidth',2,'Color','k');
                end

            end

            toc;
            app.TextArea.Value = 'Data loaded, ready to plot';
        end

        % Value changed function: CLUSTERDropDown
        function CLUSTERDropDownValueChanged(app, event)
              
            app.value = app.CLUSTERDropDown.Value; %my code
            app.valor = str2double(app.value); %my code
            app.ids = app.out.idds(app.out.clusters==app.valor); %my code
            
        end

        % Value changed function: CLUSTERDropDown_2
        function CLUSTERDropDown_2ValueChanged(app, event)

            app.value2 = app.CLUSTERDropDown_2.Value;
            app.valor2 = str2double(app.value2);
            app.ids2 = app.out.idds(app.out.clusters==app.valor2);

        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
            % DONT MODIFY THIS
            [~, timestamps, info] = load_open_ephys_data(fullfile(app.out.params.fpath, app.out.params.session_name, 'all_channels.events'));
            
            start_stim_times = timestamps(find(info.eventId ==1))-app.out.params.rec_start_time;
            end_stim_times = timestamps(find(info.eventId ==0))-app.out.params.rec_start_time;
            app.out.start_times = start_stim_times;
            app.out.end_times = end_stim_times;
            x = eval(app.out.params.xbz_file_name);
            app.out.analysis_code = x.analysis_code;
            app.out.analysis_type = x.analysis_type;
            
            if app.out.analysis_code < 99
                if length(unique(x.stimulus_ch1(:,3)))~= 1 && length(unique(x.stimulus_ch1(:,8)))~= 1
                    app.out.analysis_type = 'FRA';
                    app.out.plot_type  = 'Tuning';
                    
                else
                    app.out.plot_type  = 'Tuning';
                end
            elseif app.out.analysis_code >= 2300
                app.out.analysis_type = 'VT';
                app.out.plot_type = 'VT';
                
            elseif app.out.analysis_code >= 100 && app.out.analysis_code < 200
                app.out.analysis_type = 'User';
                app.out.plot_type = 'User';
            end
            
            %             if obj.analysis_code == 2367
            if strcmp(x.analysis_type,'User: Trill_list.txt')
                app.out.analysis_type = 'User';
                app.out.plot_type = 'User';
            end
            
            PreStim = x.pre_stimulus_record_time*1e-3; %s
            PostStim = x.post_stimulus_record_time*1e-3; %s
            StimDur = x.stimulus_ch1(:,5)*1e-3;
            
            stim_info = x.data(find(x.data(:,3) == 1 & x.data(:,4) == -1),:);
            
            
            data_new = stim_info;
            
            nreps = x.stimulus_ch1(1,4);
            nStim = max(x.stimulus_ch1(:,1));
            
            TotalReps = nStim*nreps;
            false_start = length(start_stim_times)-TotalReps;
            start_stim_times = start_stim_times(false_start+1:end);
            end_stim_times = end_stim_times(false_start+1:end);
            
            
            raster.stim = {};
            raster.rep = {};
            raster.spikes = {};
            spikes_pooled = {};
            spike_timesSU = {};
            rate.stim = {};
            rate.pre = {};
            
            SU = app.out.SUU; % MY CODE TO GET THE SU VARIABLE VALUE
            
            for id = 1:length(SU)
                
                if app.out.SU_good(id) ==1
                    
                    raster.stim{id} = [];
                    raster.rep{id} = [];
                    raster.spikes{id} = [];
                    spikes_pooled{id} = [];
                    
                    
                    for rep = 1:TotalReps
                        
                        spikes1 = app.out.spike_times{id}(find(app.out.spike_times{id}>=start_stim_times(rep)-PreStim & ...
                            app.out.spike_times{id}<=end_stim_times(rep)+ PostStim)).';
                        spikes1 = spikes1 - start_stim_times(rep);
                        spikes_pooled{id} = [spikes_pooled{id} spikes1];
                        raster.stim{id} = [raster.stim{id} data_new(rep,1)*ones(size(spikes1))];
                        raster.rep{id} = [raster.rep{id} data_new(rep,2)*ones(size(spikes1))];
                        raster.spikes{id} = [raster.spikes{id} spikes1];
                        
                        
                        spikes2 = app.out.spike_times{id}(find(app.out.spike_times{id}>=start_stim_times(rep) & ...
                            app.out.spike_times{id}<=end_stim_times(rep))).';
                        rate.stim{id}(data_new(rep,1),data_new(rep,2)) = length(spikes2)/StimDur(data_new(rep,1));
                        spikes3 = app.out.spike_times{id}(find(app.out.spike_times{id}<=start_stim_times(rep) & ...
                            app.out.spike_times{id}>=start_stim_times(rep)-PreStim)).' ;
                        rate.pre{id}(data_new(rep,1),data_new(rep,2)) = length(spikes3)/PreStim;
                    end
                end
            end
            
            stim_label = x.stimulus_ch1(:,8);
            
            % MODIFY FROM HERE 

            if (app.value=="Empty")&&(app.value2=="Empty")
                
                app.TextArea.Value = 'Please select a cluster to plot';
                delete(get(app.UIAxes, 'children'));
                delete(get(app.UIAxes2, 'children'));
                delete(get(app.UIAxes3, 'children'));
                delete(get(app.UIAxes4, 'children'));
                delete(get(app.UIAxes5, 'children'));
                delete(get(app.UIAxes6, 'children'));
                delete(get(app.UIAxes7, 'children'));
                
                delete(legend(app.UIAxes));
                delete(legend(app.UIAxes2));
                delete(legend(app.UIAxes3));
                delete(legend(app.UIAxes4));
                delete(legend(app.UIAxes7));

                delete(title(app.UIAxes, ['Waveform Cluster ' app.value]));
                delete(title(app.UIAxes3,['Tuning Curve Cluster ' app.value]));
                %delete(title(app.UIAxes4,['ISI: ' num2str(app.refract) '%, cluster ' app.value]));
                delete(title(app.UIAxes5,['Rasterplot Cluster ' app.value]));
                delete(title(app.UIAxes6,['Rasterplot Cluster ' app.value2]));
                delete(title(app.UIAxes2,['PCA Cluster' app.value]));
                delete(title(app.UIAxes7,['PCA Cluster' app.value2]));
                delete(title(app.UIAxes4,'ISI'));
                
                
            elseif (app.value~="Empty")&&(app.value2=="Empty") 
                
                app.TextArea.Value = 'Selection 1 plotted!';
                delete(get(app.UIAxes, 'children'));
                delete(get(app.UIAxes2, 'children'));
                delete(get(app.UIAxes3, 'children'));
                delete(get(app.UIAxes4, 'children'));
                delete(get(app.UIAxes5, 'children'));
                delete(get(app.UIAxes6, 'children'));
                delete(get(app.UIAxes7, 'children'));
                
                delete(legend(app.UIAxes));
                delete(legend(app.UIAxes2));
                delete(legend(app.UIAxes3));
                delete(legend(app.UIAxes4));
                delete(legend(app.UIAxes7));
                
                delete(title(app.UIAxes, ['Waveform Cluster ' app.value]));
                delete(title(app.UIAxes3,['Tuning Curve Cluster ' app.value]));
                %delete(title(app.UIAxes4,['ISI: ' num2str(app.refract) '%, cluster ' app.value]));
                delete(title(app.UIAxes5,['Rasterplot Cluster ' app.value]));
                delete(title(app.UIAxes6,['Rasterplot Cluster ' app.value2]));
                delete(title(app.UIAxes2,['PCA Cluster' app.value]));
                delete(title(app.UIAxes7,['PCA Cluster' app.value2]));
                delete(title(app.UIAxes4,'ISI'));

                
                SUrate{app.ids}.mean = mean(rate.stim{app.ids},2); %-mean(rate_pre{id},2);
                SUrate{app.ids}.error = std(rate.stim{app.ids},1,2)/sqrt(nreps);
                SUrate{app.ids}.spont = mean(mean(rate.pre{app.ids}));
                
                % WAVEFORM PLOT
                time_step = [1:60]/app.out.params.fs*1e3;
                Nb_wave = min(size(app.out.spike_times{app.ids},1),100);
                idx = 1:size(app.out.spike_times{app.ids},1);
                rand_select = randsample(idx,Nb_wave);
                
                for n = 1:length(rand_select)
                    
                    plot(app.UIAxes,time_step,app.out.waveforms.raw{app.ids}(app.out.templates{app.ids}.best_ch,:,rand_select(n)));
                    hold on
                    
                end
                plot(app.UIAxes,time_step,app.out.waveforms.mean{app.ids},'Linewidth',2,'Color','k');
                title(app.UIAxes, ['Waveform Cluster ' app.value]);
                xlabel(app.UIAxes, 'Time (ms)');
                ylabel(app.UIAxes, 'uV');
                hold off
                
                %// PCA
                Y = [];
                Y(:,:) = app.out.waveforms.raw{app.ids}(app.out.templates{app.ids}.best_ch,:,:);
                Y = Y.';
                [~,score,~] = pca(Y);
                
                X = [score(:,1) score(:,2)];
                idx = kmeans(X,2);
                %a=X(:,1);
                %b=X(:,2);
                %gscatter(a,b,idx,'rb','+o',5);
                plot(app.UIAxes2,X(idx==1,1),X(idx==1,2),'g*',X(idx==2,1),X(idx==2,2),'ms');
                title(app.UIAxes2, ['PCA Cluster ' app.value]);
                xlabel(app.UIAxes2,'a2');
                ylabel(app.UIAxes2,'b2');
                
                %ISI plot
                ISI = app.out.spike_times{app.ids}(2:end)-app.out.spike_times{app.ids}(1:end-1);
                
                edges = -3.5:0.25:2;
                [N,~] = histcounts(ISI,10.^edges);
                refract = sum(N(1:2))*100/sum(N);
                histogram(app.UIAxes4,ISI,10.^edges, 'FaceColor', 'r')
    %                         set(gca,'xscale','log')
                set(app.UIAxes4,'xscale','log')
                xticks(app.UIAxes4,[1E-4 1E-3 1E-2 1E-1 1 10]);
                xticklabels(app.UIAxes4,{0.0001, 0.001, 0.01, 0.1, 1, 10})
                xlabel(app.UIAxes4,'Time (ms)')
                %title(app.UIAxes4,'ISI');
                title(app.UIAxes4,['ISI: ' num2str(refract) '%, Cluster ' app.value]);
                
                
                if strcmp(app.out.plot_type,'Tuning') == 1
                    
                    %Tuning curve
                    errorbar(app.UIAxes3,stim_label,SUrate{app.ids}.mean,SUrate{app.ids}.error,'LineWidth',2, 'Color', 'r');
                    hold on
                    
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate{app.ids}.spont,'--k');
                    set(app.UIAxes3,'xscale','log')
%                             set(gca,'xscale','log')
                    xticks(app.UIAxes3,round((stim_label(1:4:end).')*1e2)*1e-2);
                    xtickangle(app.UIAxes3,45);
                    xlabel(app.UIAxes3,'Hz');
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3,['Tuning Curve Cluster ' app.value]);
                    %legend(app.UIAxes3, app.value);
                    
                    %RASTER PLOT
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes5,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes5,raster.spikes{app.ids},nreps*(raster.stim{app.ids}-1)+raster.rep{app.ids},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes5,'Time (s)')
                    ylabel(app.UIAxes5,'Reps')
                    yticks(app.UIAxes5,[1:nreps*2:TotalReps]+10)
                    yticks(app.UIAxes5,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes5,stim_ticks)
                    axis(app.UIAxes5,[-PreStim max(StimDur) + PostStim 0 TotalReps+1])
                    hold off
                    title(app.UIAxes5,['Rasterplot Cluster ' app.value]);
                    drawnow()
                    
                    
                elseif strcmp(app.out.plot_type,'VT') == 1
                    stim_number = x.stimulus_ch1(:,1);
                    stim_length = x.stimulus_ch1(:,5);
                    for i = 9:22
                        VT_para(i-8) = length(unique(x.stimulus_ch1(:,i)));
                    end
                    stim_label = x.stimulus_ch1(:,find(VT_para ~= 1)+8);
                    if app.out.analysis_code == 2320 || app.out.analysis_code == 2335
                        stim_label = x.stimulus_ch1(:,10);
                    end
                    
                    %Tuning Curve
                    SUrate{app.ids}.mean_fr = SUrate{app.ids}.mean./stim_length;
                    errorbar(app.UIAxes3,stim_label,SUrate{app.ids}.mean,SUrate{app.ids}.error,'LineWidth',2, 'Color', 'r');
                    hold on
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate{app.ids}.spont,'--k');
                    %                     xticks(stim_number);
                    %                     xticklabels(stim_label)
                    xtickangle(app.UIAxes3,45);
                    %                 xlabel('Hz')
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3, ['Tuning Curve Cluster ' app.value]);
                    legend(app.UIAxes3, app.value);
                    
                    %RASTER PLOT
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes5,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes5,raster.spikes{app.ids},nreps*(raster.stim{app.ids}-1)+raster.rep{app.ids},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes5,'Time (s)')
                    ylabel(app.UIAxes5,'Reps')
                    yticks(app.UIAxes5,[1:nreps*2:TotalReps]+10)
                    yticks(app.UIAxes5,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes5,stim_ticks);
                    axis(app.UIAxes5,[-PreStim max(StimDur) + PostStim 0 TotalReps+1]);
                    hold off
                    title(app.UIAxes5,['Rasterplot Cluster ' app.value]);
                    drawnow();
                    
                elseif strcmp(app.out.plot_type,'User') == 1
                    
                    %Tuning Curve
                    stim_label = {};
                    stim_number = x.stimulus_ch1(:,1);
                    stim_length = x.stimulus_ch1(:,5);
                    for st = 1: length(stim_number)
                        idx1 = strfind(x.user_stimulus_desc_ch1{st},': ')+2;
                        idx2 = strfind(x.user_stimulus_desc_ch1{st},'_m')-1;
                        idx3 = strfind(x.user_stimulus_desc_ch1{st},'rev');
                        stim_label{st} = x.user_stimulus_desc_ch1{st}(idx1:idx2);
                        if isempty(idx3) ~=1
                            stim_label{st} = [stim_label{st} '_rev'];
                        end
                        
                    end
                    SUrate{app.ids}.mean_fr = SUrate{app.ids}.mean./stim_length;
                    errorbar(app.UIAxes3,stim_number,SUrate{app.ids}.mean,SUrate{app.ids}.error,'LineWidth',2, 'Color', 'r');
                    hold on
                    plot(app.UIAxes3,stim_number,ones(1,length(stim_label))*SUrate{app.ids}.spont,'--k');
                    xticks(app.UIAxes3,stim_number);
                    xticklabels(app.UIAxes3,stim_label);
                    xtickangle(app.UIAxes3,45);
                    %                 xlabel('Hz')
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3, ['Tuning Curve Cluster ' app.value]);
                    %legend(app.UIAxes3, app.value);
                    
                    %RASTERPLOT
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes5,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes5,raster.spikes{app.ids},nreps*(raster.stim{app.ids}-1)+raster.rep{app.ids},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes5,'Time (s)');
                    ylabel(app.UIAxes5,'Teps');
                    yticks(app.UIAxes5,[1:nreps:TotalReps]+5);
                    yticklabels(app.UIAxes5,stim_label);
                    axis(app.UIAxes5,[-PreStim max(StimDur) + PostStim 0 TotalReps+5]);
                    hold off
                    title(app.UIAxes5,['Rasterplot Cluster ' app.value]);
                    drawnow()
                    
                end
                
                
            elseif (app.value=="Empty")&&(app.value2~="Empty") 
                
                app.TextArea.Value = 'Selection 2 plotted!';
                delete(get(app.UIAxes, 'children'));
                delete(get(app.UIAxes2, 'children'));
                delete(get(app.UIAxes3, 'children'));
                delete(get(app.UIAxes4, 'children'));
                delete(get(app.UIAxes5, 'children'));
                delete(get(app.UIAxes6, 'children'));
                delete(get(app.UIAxes7, 'children'));
                
                delete(legend(app.UIAxes));
                delete(legend(app.UIAxes2));
                delete(legend(app.UIAxes3));
                delete(legend(app.UIAxes4));
                
                delete(title(app.UIAxes, ['Waveform Cluster ' app.value]));
                delete(title(app.UIAxes3,['Tuning Curve Cluster ' app.value]));
                %delete(title(app.UIAxes4,['ISI: ' num2str(app.refract) '%, cluster ' app.value]));
                delete(title(app.UIAxes5,['Rasterplot Cluster ' app.value]));
                delete(title(app.UIAxes6,['Rasterplot Cluster ' app.value2]));
                delete(title(app.UIAxes2,['PCA Cluster' app.value]));
                delete(title(app.UIAxes7,['PCA Cluster' app.value2]));
                delete(title(app.UIAxes4,'ISI'));

                
                SUrate2{app.ids2}.mean = mean(rate.stim{app.ids2},2); %-mean(rate_pre{id},2);
                SUrate2{app.ids2}.error = std(rate.stim{app.ids2},1,2)/sqrt(nreps);
                SUrate2{app.ids2}.spont = mean(mean(rate.pre{app.ids2}));
                
                % WAVEFORM PLOT
                time_step = [1:60]/app.out.params.fs*1e3;
                Nb_wave = min(size(app.out.spike_times{app.ids2},1),100);
                idx = 1:size(app.out.spike_times{app.ids2},1);
                rand_select = randsample(idx,Nb_wave);
                
                for n = 1:length(rand_select)
                    
                    plot(app.UIAxes,time_step,app.out.waveforms.raw{app.ids2}(app.out.templates{app.ids2}.best_ch,:,rand_select(n)));
                    hold on
                    
                end
                plot(app.UIAxes,time_step,app.out.waveforms.mean{app.ids2},'Linewidth',2,'Color','k');
                title(app.UIAxes, ['Waveform Cluster ' app.value2]);
                xlabel(app.UIAxes, 'Time (ms)');
                ylabel(app.UIAxes, 'uV');
                hold off
                
                % PCA
                Y = [];
                Y(:,:) = app.out.waveforms.raw{app.ids2}(app.out.templates{app.ids2}.best_ch,:,:);
                Y = Y.';
                [~,score,~] = pca(Y);
                
                X = [score(:,1) score(:,2)];
                idx = kmeans(X,2);
                %a=X(:,1);
                %b=X(:,2);
                %gscatter(a,b,idx,'rb','+o',5);
                plot(app.UIAxes7,X(idx==1,1),X(idx==1,2),'g*',X(idx==2,1),X(idx==2,2),'ms');
                title(app.UIAxes7, ['PCA Cluster ' app.value2]);
                xlabel(app.UIAxes7,'a2');
                ylabel(app.UIAxes7,'b2');
                
                
                %ISI plot
                ISI = app.out.spike_times{app.ids2}(2:end)-app.out.spike_times{app.ids2}(1:end-1);
                
                edges = -3.5:0.25:2;
                [N,~] = histcounts(ISI,10.^edges);
                refract = sum(N(1:2))*100/sum(N);
                histogram(app.UIAxes4,ISI,10.^edges, 'FaceColor', 'b')
    %                         set(gca,'xscale','log')
                set(app.UIAxes4,'xscale','log')
                xticks(app.UIAxes4,[1E-4 1E-3 1E-2 1E-1 1 10]);
                xticklabels(app.UIAxes4,{0.0001, 0.001, 0.01, 0.1, 1, 10})
                xlabel(app.UIAxes4,'Time (ms)')
                title(app.UIAxes4,['ISI: ' num2str(refract) '%, Cluster ' app.value2]);
                
                
                if strcmp(app.out.plot_type,'Tuning') == 1
                    
                    %Tuning curve
                    errorbar(app.UIAxes3,stim_label,SUrate2{app.ids2}.mean,SUrate2{app.ids2}.error,'LineWidth',2, 'Color', 'b');
                    hold on
                    
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate2{app.ids2}.spont,'--k');
                    set(app.UIAxes3,'xscale','log')
%                             set(gca,'xscale','log')
                    xticks(app.UIAxes3,round((stim_label(1:4:end).')*1e2)*1e-2);
                    xtickangle(app.UIAxes3,45);
                    xlabel(app.UIAxes3,'Hz');
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3,['Tuning Curve Cluster ' app.value2]);
                    
                    
                    %RASTER PLOT
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes6,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes6,raster.spikes{app.ids2},nreps*(raster.stim{app.ids2}-1)+raster.rep{app.ids2},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes6,'Time (s)')
                    ylabel(app.UIAxes6,'Reps')
                    yticks(app.UIAxes6,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes6,stim_ticks)
                    axis(app.UIAxes6,[-PreStim max(StimDur) + PostStim 0 TotalReps+1])
                    hold off
                    title(app.UIAxes6,['Rasterplot Cluster ' app.value2]);
                    drawnow()
                    
                    
                elseif strcmp(app.out.plot_type,'VT') == 1
                    stim_number = x.stimulus_ch1(:,1);
                    stim_length = x.stimulus_ch1(:,5);
                    for i = 9:22
                        VT_para(i-8) = length(unique(x.stimulus_ch1(:,i)));
                    end
                    stim_label = x.stimulus_ch1(:,find(VT_para ~= 1)+8);
                    if app.out.analysis_code == 2320 || app.out.analysis_code == 2335
                        stim_label = x.stimulus_ch1(:,10);
                    end
                    
                    %Tuning Curve
                    SUrate2{app.ids2}.mean_fr = SUrate2{app.ids2}.mean./stim_length;
                    errorbar(app.UIAxes3,stim_label,SUrate2{app.ids2}.mean,SUrate2{app.ids2}.error,'LineWidth',2, 'Color', 'b');
                    hold on
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate2{app.ids2}.spont,'--k');
                    %                     xticks(stim_number);
                    %                     xticklabels(stim_label)
                    xtickangle(app.UIAxes3,45);
                    %                 xlabel('Hz')
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3,['Tuning Curve Cluster ' app.value2]);
                    %legend(app.UIAxes3, app.value2);
                    
                    %RASTER PLOT
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes6,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes6,raster.spikes{app.ids2},nreps*(raster.stim{app.ids2}-1)+raster.rep{app.ids2},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes6,'Time (s)')
                    ylabel(app.UIAxes6,'Reps')
                    yticks(app.UIAxes6,[1:nreps*2:TotalReps]+10)
                    yticks(app.UIAxes6,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes6,stim_ticks);
                    axis(app.UIAxes6,[-PreStim max(StimDur) + PostStim 0 TotalReps+1]);
                    hold off
                    title(app.UIAxes6,['Rasterplot Cluster ' app.value2]);
                    drawnow();
                    
                elseif strcmp(app.out.plot_type,'User') == 1
                    
                    %Tuning Curve
                    stim_label = {};
                    stim_number = x.stimulus_ch1(:,1);
                    stim_length = x.stimulus_ch1(:,5);
                    for st = 1: length(stim_number)
                        idx1 = strfind(x.user_stimulus_desc_ch1{st},': ')+2;
                        idx2 = strfind(x.user_stimulus_desc_ch1{st},'_m')-1;
                        idx3 = strfind(x.user_stimulus_desc_ch1{st},'rev');
                        stim_label{st} = x.user_stimulus_desc_ch1{st}(idx1:idx2);
                        if isempty(idx3) ~=1
                            stim_label{st} = [stim_label{st} '_rev'];
                        end
                        
                    end
                    SUrate2{app.ids2}.mean_fr = SUrate2{app.ids2}.mean./stim_length;
                    errorbar(app.UIAxes3,stim_number,SUrate2{app.ids2}.mean,SUrate2{app.ids2}.error,'LineWidth',2, 'Color', 'b');
                    hold on
                    plot(app.UIAxes3,stim_number,ones(1,length(stim_label))*SUrate2{app.ids2}.spont,'--k');
                    xticks(app.UIAxes3,stim_number);
                    xticklabels(app.UIAxes3,stim_label);
                    xtickangle(app.UIAxes3,45);
                    %                 xlabel('Hz')
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3,['Tuning Curve Cluster ' app.value2]);
                    %legend(app.UIAxes3, app.value2);
                    
                    %RASTERPLOT
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes6,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes6,raster.spikes{app.ids2},nreps*(raster.stim{app.ids2}-1)+raster.rep{app.ids2},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes6,'Time (s)');
                    ylabel(app.UIAxes6,'Teps');
                    yticks(app.UIAxes6,[1:nreps:TotalReps]+5);
                    yticklabels(app.UIAxes6,stim_label);
                    axis(app.UIAxes6,[-PreStim max(StimDur) + PostStim 0 TotalReps+5]);
                    hold off
                    title(app.UIAxes6,['Rasterplot Cluster ' app.value2]);
                    drawnow()
                    
                end
                
            else 
                
                app.TextArea.Value = 'Both selections plotted!';
                delete(get(app.UIAxes, 'children'));
                delete(get(app.UIAxes2, 'children'));
                delete(get(app.UIAxes3, 'children'));
                delete(get(app.UIAxes4, 'children'));
                delete(get(app.UIAxes5, 'children'));
                delete(get(app.UIAxes6, 'children'));
                delete(get(app.UIAxes7, 'children'));
                
                delete(legend(app.UIAxes));
                delete(legend(app.UIAxes3));
                delete(legend(app.UIAxes4));
                
                delete(title(app.UIAxes, ['Waveform Cluster ' app.value]));
                delete(title(app.UIAxes3,['Tuning Curve Cluster ' app.value]));
                %delete(title(app.UIAxes4,['ISI: ' num2str(app.refract) '%, cluster ' app.value]));
                delete(title(app.UIAxes5,['Rasterplot Cluster ' app.value]));
                delete(title(app.UIAxes6,['Rasterplot Cluster ' app.value2]));
                delete(title(app.UIAxes2,['PCA Cluster' app.value]));
                delete(title(app.UIAxes7,['PCA Cluster' app.value2]));
                delete(title(app.UIAxes4,'ISI'));
                
                
                SUrate{app.ids}.mean = mean(rate.stim{app.ids},2); %-mean(rate_pre{id},2);
                SUrate{app.ids}.error = std(rate.stim{app.ids},1,2)/sqrt(nreps);
                SUrate{app.ids}.spont = mean(mean(rate.pre{app.ids}));
                
                SUrate2{app.ids2}.mean = mean(rate.stim{app.ids2},2); %-mean(rate_pre{id},2);
                SUrate2{app.ids2}.error = std(rate.stim{app.ids2},1,2)/sqrt(nreps);
                SUrate2{app.ids2}.spont = mean(mean(rate.pre{app.ids2}));
                
                % WAVEFORM PLOTS
                time_step = [1:60]/app.out.params.fs*1e3;

                plot(app.UIAxes,time_step,app.out.waveforms.mean{app.ids},'Linewidth',2, 'Color', 'r');
                hold on
                plot(app.UIAxes,time_step,app.out.waveforms.mean{app.ids2},'Linewidth',2, 'Color', 'b');
                title(app.UIAxes,'Waveform');
                xlabel(app.UIAxes, 'Time (ms)');
                ylabel(app.UIAxes, 'uV');
                legend(app.UIAxes, app.value, app.value2);
                hold off
                
                 % PCA 1
                Y1 = [];
                Y1(:,:) = app.out.waveforms.raw{app.ids}(app.out.templates{app.ids}.best_ch,:,:);
                Y1 = Y1.';
                [~,score1,~] = pca(Y1);
                
                X1 = [score1(:,1) score1(:,2)];
                idx1 = kmeans(X1,2);
                %a1=X1(:,1);
                %b1=X1(:,2);
                %gscatter(a1,b1,idx1,'rb','+o',5);
                plot(app.UIAxes2,X1(idx1==1,1),X1(idx1==1,2),'g*',X1(idx1==2,1),X1(idx1==2,2),'ms');
                title(app.UIAxes2, ['PCA Cluster ' app.value]);
                xlabel(app.UIAxes2,'a2');
                ylabel(app.UIAxes2,'b2');
                
                 % PCA 2
                Y2 = [];
                Y2(:,:) = app.out.waveforms.raw{app.ids2}(app.out.templates{app.ids2}.best_ch,:,:);
                Y2 = Y2.';
                [~,score2,~] = pca(Y2);
                
                X2 = [score2(:,1) score2(:,2)];
                idx2 = kmeans(X2,2);
                %a2=X2(:,1);
                %b2=X2(:,2);
                %gscatter(a2,b2,idx2,'rb','+o',5);
                plot(app.UIAxes7,X2(idx2==1,1),X2(idx2==1,2),'g*',X2(idx2==2,1),X2(idx2==2,2),'ms');
                title(app.UIAxes7, ['PCA Cluster ' app.value2]);
                xlabel(app.UIAxes7,'a2');
                ylabel(app.UIAxes7,'b2');
                
                % ISI PLOTS
                ISI = app.out.spike_times{app.ids}(2:end)-app.out.spike_times{app.ids}(1:end-1);
                ISI2 = app.out.spike_times{app.ids2}(2:end)-app.out.spike_times{app.ids2}(1:end-1);
                
                edges = -3.5:0.25:2;
                [N,~] = histcounts(ISI,10.^edges);
                [N2,~] = histcounts(ISI2,10.^edges);
                refract = sum(N(1:2))*100/sum(N);
                refract2 = sum(N2(1:2))*100/sum(N2);
                histogram(app.UIAxes4,ISI,10.^edges, 'FaceColor', 'r');
                hold on
                histogram(app.UIAxes4,ISI2,10.^edges, 'FaceColor', 'b');
                set(app.UIAxes4,'xscale','log')
                xticks(app.UIAxes4,[1E-4 1E-3 1E-2 1E-1 1 10]);
                xticklabels(app.UIAxes4,{0.0001, 0.001, 0.01, 0.1, 1, 10})
                xlabel(app.UIAxes4,'Time (ms)')
                title(app.UIAxes4,'ISI');
                legend(app.UIAxes4,[num2str(refract) '%' ' (cluster ' app.value ')'],[num2str(refract2) '%' ' (cluster ' app.value2 ')']);
                hold off
                
                if strcmp(app.out.plot_type,'Tuning') == 1
                    
                    %Tuning curve
                    errorbar(app.UIAxes3,stim_label,SUrate{app.ids}.mean,SUrate{app.ids}.error,'LineWidth',2, 'Color', 'r');
                    hold on
                    errorbar(app.UIAxes3,stim_label,SUrate2{app.ids2}.mean,SUrate2{app.ids2}.error,'LineWidth',2, 'Color', 'b');
                   
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate{app.ids}.spont,'--k');
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate2{app.ids2}.spont,'--k');
                    
                    set(app.UIAxes3,'xscale','log')
%                             set(gca,'xscale','log')
                    xticks(app.UIAxes3,round((stim_label(1:4:end).')*1e2)*1e-2);
                    xtickangle(app.UIAxes3,45);
                    xlabel(app.UIAxes3,'Hz');
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3,'Tuning Curve');
                    legend(app.UIAxes3, app.value, app.value2);
                    hold off
                    
                    %RASTERPLOT 1
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes5,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes5,raster.spikes{app.ids},nreps*(raster.stim{app.ids}-1)+raster.rep{app.ids},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes5,'Time (s)')
                    ylabel(app.UIAxes5,'Reps')
                    yticks(app.UIAxes5,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes5,stim_ticks)
                    axis(app.UIAxes5,[-PreStim max(StimDur) + PostStim 0 TotalReps+1])
                    hold off
                    title(app.UIAxes5,['Rasterplot Cluster ' app.value]);
                    drawnow()
                    
                    %RASTERPLOT 2
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes6,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes6,raster.spikes{app.ids2},nreps*(raster.stim{app.ids2}-1)+raster.rep{app.ids2},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes6,'Time (s)')
                    ylabel(app.UIAxes6,'Reps')
                    yticks(app.UIAxes6,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes6,stim_ticks)
                    axis(app.UIAxes6,[-PreStim max(StimDur) + PostStim 0 TotalReps+1])
                    hold off
                    title(app.UIAxes6,['Rasterplot Cluster ' app.value2]);
                    drawnow()
                    
                    
                elseif strcmp(app.out.plot_type,'VT') == 1
                    stim_number = x.stimulus_ch1(:,1);
                    stim_length = x.stimulus_ch1(:,5);
                    for i = 9:22
                        VT_para(i-8) = length(unique(x.stimulus_ch1(:,i)));
                    end
                    stim_label = x.stimulus_ch1(:,find(VT_para ~= 1)+8);
                    if app.out.analysis_code == 2320 || app.out.analysis_code == 2335
                        stim_label = x.stimulus_ch1(:,10);
                    end
                    
                    %Tuning Curve
                    SUrate{app.ids}.mean_fr = SUrate{app.ids}.mean./stim_length;
                    SUrate2{app.ids2}.mean_fr = SUrate2{app.ids2}.mean./stim_length;
                    errorbar(app.UIAxes3,stim_label,SUrate{app.ids}.mean,SUrate{app.ids}.error,'LineWidth',2, 'Color', 'r');
                    hold on
                    errorbar(app.UIAxes3,stim_label,SUrate2{app.ids2}.mean,SUrate2{app.ids2}.error,'LineWidth',2, 'Color', 'b');
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate{app.ids}.spont,'--k');
                    plot(app.UIAxes3,stim_label,ones(1,length(stim_label))*SUrate2{app.ids2}.spont,'--k');
                    %                     xticks(stim_number);
                    %                     xticklabels(stim_label)
                    xtickangle(app.UIAxes3,45);
                    %                 xlabel('Hz')
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3,'Tuning Curve');
                    legend(app.UIAxes3, app.value, app.value2);
                    hold off
                    
                    %RASTERPLOT 1
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes5,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes5,raster.spikes{app.ids},nreps*(raster.stim{app.ids}-1)+raster.rep{app.ids},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes5,'Time (s)')
                    ylabel(app.UIAxes5,'Reps')
                    yticks(app.UIAxes5,[1:nreps*2:TotalReps]+10)
                    yticks(app.UIAxes5,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes5,stim_ticks);
                    axis(app.UIAxes5,[-PreStim max(StimDur) + PostStim 0 TotalReps+1]);
                    hold off
                    title(app.UIAxes5,['Rasterplot Cluster ' app.value]);
                    drawnow();
                    
                    %RASTERPLOT 2
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes6,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes6,raster.spikes{app.ids2},nreps*(raster.stim{app.ids2}-1)+raster.rep{app.ids2},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes6,'Time (s)')
                    ylabel(app.UIAxes6,'Reps')
                    yticks(app.UIAxes6,[1:nreps*2:TotalReps]+10)
                    yticks(app.UIAxes6,[1:nreps*2:TotalReps]+10)
                    stim_ticks = {};
                    if length(stim_label)>2
                        for stim = 1:length(stim_label)/2
                            stim_ticks{stim}=num2str(round(stim_label(stim*2)*10)/10);
                        end
                    else
                        for stim = 1:length(stim_label)
                            stim_ticks{stim}=num2str(round(stim_label(stim)*10)/10);
                        end
                    end
                    yticklabels(app.UIAxes6,stim_ticks);
                    axis(app.UIAxes6,[-PreStim max(StimDur) + PostStim 0 TotalReps+1]);
                    hold off
                    title(app.UIAxes6,['Rasterplot Cluster ' app.value2]);
                    drawnow();
                    
                elseif strcmp(app.out.plot_type,'User') == 1
                    
                    %Tuning Curve
                    stim_label = {};
                    stim_number = x.stimulus_ch1(:,1);
                    stim_length = x.stimulus_ch1(:,5);
                    for st = 1: length(stim_number)
                        idx1 = strfind(x.user_stimulus_desc_ch1{st},': ')+2;
                        idx2 = strfind(x.user_stimulus_desc_ch1{st},'_m')-1;
                        idx3 = strfind(x.user_stimulus_desc_ch1{st},'rev');
                        stim_label{st} = x.user_stimulus_desc_ch1{st}(idx1:idx2);
                        if isempty(idx3) ~=1
                            stim_label{st} = [stim_label{st} '_rev'];
                        end
                        
                    end
                    SUrate{app.ids}.mean_fr = SUrate{app.ids}.mean./stim_length;
                    SUrate2{app.ids2}.mean_fr = SUrate2{app.ids2}.mean./stim_length;
                    errorbar(app.UIAxes3,stim_number,SUrate{app.ids}.mean,SUrate{app.ids}.error,'LineWidth',2, 'Color', 'r');
                    hold on
                    errorbar(app.UIAxes3,stim_number,SUrate2{app.ids2}.mean,SUrate2{app.ids2}.error,'LineWidth',2, 'Color', 'b');
                    plot(app.UIAxes3,stim_number,ones(1,length(stim_label))*SUrate{app.ids}.spont,'--k');
                    plot(app.UIAxes3,stim_number,ones(1,length(stim_label))*SUrate2{app.ids2}.spont,'--k');
                    xticks(app.UIAxes3,stim_number);
                    xticklabels(app.UIAxes3,stim_label);
                    xtickangle(app.UIAxes3,45);
                    %                 xlabel('Hz')
                    ylabel(app.UIAxes3,'Firing Rate (spikes/s)');
                    title(app.UIAxes3,'Tuning Curve');
                    legend(app.UIAxes3, app.value, app.value2);
                    hold off
                    
                    %RASTERPLOT 1
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes5,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes5,raster.spikes{app.ids},nreps*(raster.stim{app.ids}-1)+raster.rep{app.ids},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes5,'Time (s)');
                    ylabel(app.UIAxes5,'Teps');
                    yticks(app.UIAxes5,[1:nreps:TotalReps]+5);
                    yticklabels(app.UIAxes5,stim_label);
                    axis(app.UIAxes5,[-PreStim max(StimDur) + PostStim 0 TotalReps+5]);
                    hold off
                    title(app.UIAxes5,['Rasterplot Cluster ' app.value]);
                    drawnow()
                    
                    %RASTERPLOT 2
                    for st = 1:length(StimDur)
                        rectangle(app.UIAxes6,'Position',[0 nreps*(st-1),StimDur(st) nreps],'FaceColor',[0.9,0.9,0.9],'EdgeColor','none')
                    end
                    hold on
                    plot(app.UIAxes6,raster.spikes{app.ids2},nreps*(raster.stim{app.ids2}-1)+raster.rep{app.ids2},'k.','MarkerSize',15);
                    %     pause
                    xlabel(app.UIAxes6,'Time (s)');
                    ylabel(app.UIAxes6,'Teps');
                    yticks(app.UIAxes6,[1:nreps:TotalReps]+5);
                    yticklabels(app.UIAxes6,stim_label);
                    axis(app.UIAxes6,[-PreStim max(StimDur) + PostStim 0 TotalReps+5]);
                    hold off
                    title(app.UIAxes6,['Rasterplot Cluster ' app.value2]);
                    drawnow()
                    
                end
                
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            mkdir(['C:\Users\Seth\Desktop\Saul\Dummy_Save_Folder\' app.animal_name]);
            sdir = fullfile('C:\Users\Seth\Desktop\Saul\Dummy_Save_Folder\', app.animal_name);
%             disp(sdir);
%             if ~exist(sdir)
%                 app.TextArea.Value = 'Not exists';
%             else
%                 app.TextArea.Value = 'Yes';
%             end
            if isempty(app.clust)
                app.TextArea.Value = 'Please input the data and run the GUI first';
            else
                if exist(fullfile(sdir,filesep,'M12E_unit_list.mat'))
                    load(fullfile(sdir,'M12E_unit_list.mat'));
                    
                    if  ~isempty(find(strcmp(M12E_unit_list.data(:,4),app.out.params.xbz_file_name)))
                        app.TextArea.Value = 'Units already saved';
                    end
                else
                    app.TextArea.Value = 'Saving data...';
                    pause(0.2);
                    app.out.save_Units(sdir);
                    app.TextArea.Value = 'Data saved...';
                end
            end
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SearchpathEditFieldLabel = uilabel(app.UIFigure);
            app.SearchpathEditFieldLabel.Text = 'Search path';
            app.SearchpathEditField = uieditfield(app.UIFigure, 'text');
            app.SearchButtonPath = uibutton(app.UIFigure, 'push');
            app.SearchButtonPath.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPathPushed, true);
            app.SearchButtonPath.Text = 'Search';
            app.PCNameEditFieldLabel = uilabel(app.UIFigure);
            app.PCNameEditFieldLabel.Text = 'PC Name';
            app.PCNameEditField = uieditfield(app.UIFigure, 'text');
            app.AnimalNameEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalNameEditFieldLabel.Text = 'Animal Name';
            app.AnimalNameEditField = uieditfield(app.UIFigure, 'text');
            app.SessionIDEditFieldLabel = uilabel(app.UIFigure);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.SessionIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParametersfileEditFieldLabel = uilabel(app.UIFigure);
            app.ParametersfileEditFieldLabel.Text = 'Parameters file';
            app.ParametersfileEditField = uieditfield(app.UIFigure, 'text');
            app.SearchButtonXFile = uibutton(app.UIFigure, 'push');
            app.SearchButtonXFile.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonXFilePushed, true);
            app.SearchButtonXFile.Text = 'Search';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.CLUSTERLabel = uilabel(app.UIFigure);
            app.CLUSTERLabel.Text = 'CLUSTER:';
            app.CLUSTERDropDown = uidropdown(app.UIFigure);
            app.CLUSTERDropDown.Items = {'Option 1'};
            app.CLUSTERDropDown.ValueChangedFcn = createCallbackFcn(app, @CLUSTERDropDownValueChanged, true);
            app.CLUSTERLabel_2 = uilabel(app.UIFigure);
            app.CLUSTERLabel_2.Text = 'CLUSTER:';
            app.CLUSTERDropDown_2 = uidropdown(app.UIFigure);
            app.CLUSTERDropDown_2.Items = {'Option 1'};
            app.CLUSTERDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CLUSTERDropDown_2ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PCA')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Tuning Curve')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'ISI')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Rasterplot')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Rasterplot')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.TextArea = uitextarea(app.UIFigure);
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'PCA')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes8_1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_1, '')
            ylabel(app.UIAxes8_1, '')
            app.UIAxes8_1.XTick = [];
            app.UIAxes8_1.XTickLabel = '';
            app.UIAxes8_1.YTick = [];
            app.UIAxes8_1.YTickLabel = '';
            app.UIAxes8_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_2, '')
            ylabel(app.UIAxes8_2, '')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.UIAxes8_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_3, '')
            ylabel(app.UIAxes8_3, '')
            app.UIAxes8_3.XTick = [];
            app.UIAxes8_3.YTick = [];
            app.UIAxes8_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_4, '')
            ylabel(app.UIAxes8_4, '')
            app.UIAxes8_4.XTick = [];
            app.UIAxes8_4.YTick = [];
            app.UIAxes8_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_5, '')
            ylabel(app.UIAxes8_5, '')
            app.UIAxes8_5.XTick = [];
            app.UIAxes8_5.YTick = [];
            app.UIAxes8_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_6, '')
            ylabel(app.UIAxes8_6, '')
            app.UIAxes8_6.XTick = [];
            app.UIAxes8_6.YTick = [];
            app.UIAxes8_7 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_7, '')
            ylabel(app.UIAxes8_7, '')
            app.UIAxes8_7.XTick = [];
            app.UIAxes8_7.YTick = [];
            app.UIAxes8_8 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_8, '')
            ylabel(app.UIAxes8_8, '')
            app.UIAxes8_8.XTick = [];
            app.UIAxes8_8.YTick = [];
            app.UIAxes8_9 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_9, '')
            ylabel(app.UIAxes8_9, '')
            app.UIAxes8_9.XTick = [];
            app.UIAxes8_9.YTick = [];
            app.UIAxes8_10 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_10, '')
            ylabel(app.UIAxes8_10, '')
            app.UIAxes8_10.XTick = [];
            app.UIAxes8_10.YTick = [];
            app.UIAxes8_11 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_11, '')
            ylabel(app.UIAxes8_11, '')
            app.UIAxes8_11.XTick = [];
            app.UIAxes8_11.YTick = [];
            app.UIAxes8_12 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_12, '')
            ylabel(app.UIAxes8_12, '')
            app.UIAxes8_12.XTick = [];
            app.UIAxes8_12.YTick = [];
            app.UIAxes8_13 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_13, '')
            ylabel(app.UIAxes8_13, '')
            app.UIAxes8_13.XTick = [];
            app.UIAxes8_13.YTick = [];
            app.UIAxes8_14 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_14, '')
            ylabel(app.UIAxes8_14, '')
            app.UIAxes8_14.XTick = [];
            app.UIAxes8_14.YTick = [];
            app.UIAxes8_15 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_15, '')
            ylabel(app.UIAxes8_15, '')
            app.UIAxes8_15.XTick = [];
            app.UIAxes8_15.YTick = [];
            app.UIAxes8_16 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_16, '')
            ylabel(app.UIAxes8_16, '')
            app.UIAxes8_16.XTick = [];
            app.UIAxes8_16.YTick = [];
            app.UIAxes8_17 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_17, '')
            ylabel(app.UIAxes8_17, '')
            app.UIAxes8_17.XTick = [];
            app.UIAxes8_17.YTick = [];
            app.UIAxes8_18 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_18, '')
            ylabel(app.UIAxes8_18, '')
            app.UIAxes8_18.XTick = [];
            app.UIAxes8_18.YTick = [];
            app.UIAxes8_19 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_19, '')
            ylabel(app.UIAxes8_19, '')
            app.UIAxes8_19.XTick = [];
            app.UIAxes8_19.YTick = [];
            app.UIAxes8_20 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_20, '')
            ylabel(app.UIAxes8_20, '')
            app.UIAxes8_20.XTick = [];
            app.UIAxes8_20.YTick = [];
            app.UIAxes8_21 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_21, '')
            ylabel(app.UIAxes8_21, '')
            app.UIAxes8_21.XTick = [];
            app.UIAxes8_21.YTick = [];
            app.UIAxes8_22 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_22, '')
            ylabel(app.UIAxes8_22, '')
            app.UIAxes8_22.XTick = [];
            app.UIAxes8_22.YTick = [];
            app.UIAxes8_23 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_23, '')
            ylabel(app.UIAxes8_23, '')
            app.UIAxes8_23.XTick = [];
            app.UIAxes8_23.XTickLabel = '';
            app.UIAxes8_23.YTick = [];
            app.UIAxes8_23.YTickLabel = '';
            app.UIAxes8_24 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_24, '')
            ylabel(app.UIAxes8_24, '')
            app.UIAxes8_24.XTick = [];
            app.UIAxes8_24.XTickLabel = '';
            app.UIAxes8_24.YTick = [];
            app.UIAxes8_24.YTickLabel = '';
            app.UIAxes8_25 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_25, '')
            ylabel(app.UIAxes8_25, '')
            app.UIAxes8_25.XTick = [];
            app.UIAxes8_25.XTickLabel = '';
            app.UIAxes8_25.YTick = [];
            app.UIAxes8_25.YTickLabel = '';
            app.UIAxes8_26 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_26, '')
            ylabel(app.UIAxes8_26, '')
            app.UIAxes8_26.XTick = [];
            app.UIAxes8_26.XTickLabel = '';
            app.UIAxes8_26.YTick = [];
            app.UIAxes8_26.YTickLabel = '';
            app.UIAxes8_27 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_27, '')
            ylabel(app.UIAxes8_27, '')
            app.UIAxes8_27.XTick = [];
            app.UIAxes8_27.XTickLabel = '';
            app.UIAxes8_27.YTick = [];
            app.UIAxes8_27.YTickLabel = '';
            app.UIAxes8_28 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_28, '')
            ylabel(app.UIAxes8_28, '')
            app.UIAxes8_28.XTick = [];
            app.UIAxes8_28.XTickLabel = '';
            app.UIAxes8_28.YTick = [];
            app.UIAxes8_28.YTickLabel = '';
            app.UIAxes8_29 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_29, '')
            ylabel(app.UIAxes8_29, '')
            app.UIAxes8_29.XTick = [];
            app.UIAxes8_29.XTickLabel = '';
            app.UIAxes8_29.YTick = [];
            app.UIAxes8_29.YTickLabel = '';
            app.UIAxes8_30 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_30, '')
            ylabel(app.UIAxes8_30, '')
            app.UIAxes8_30.XTick = [];
            app.UIAxes8_30.XTickLabel = '';
            app.UIAxes8_30.YTick = [];
            app.UIAxes8_30.YTickLabel = '';
            app.UIAxes8_31 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_31, '')
            ylabel(app.UIAxes8_31, '')
            app.UIAxes8_31.XTick = [];
            app.UIAxes8_31.XTickLabel = '';
            app.UIAxes8_31.YTick = [];
            app.UIAxes8_31.YTickLabel = '';
            app.UIAxes8_32 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_32, '')
            ylabel(app.UIAxes8_32, '')
            app.UIAxes8_32.XTick = [];
            app.UIAxes8_32.XTickLabel = '';
            app.UIAxes8_32.YTick = [];
            app.UIAxes8_32.YTickLabel = '';
            app.UIAxes8_33 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_33, '')
            ylabel(app.UIAxes8_33, '')
            app.UIAxes8_33.XTick = [];
            app.UIAxes8_33.XTickLabel = '';
            app.UIAxes8_33.YTick = [];
            app.UIAxes8_33.YTickLabel = '';
            app.UIAxes8_34 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_34, '')
            ylabel(app.UIAxes8_34, '')
            app.UIAxes8_34.XTick = [];
            app.UIAxes8_34.XTickLabel = '';
            app.UIAxes8_34.YTick = [];
            app.UIAxes8_34.YTickLabel = '';
            app.UIAxes8_35 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_35, '')
            ylabel(app.UIAxes8_35, '')
            app.UIAxes8_35.XTick = [];
            app.UIAxes8_35.XTickLabel = '';
            app.UIAxes8_35.YTick = [];
            app.UIAxes8_35.YTickLabel = '';
            app.UIAxes8_36 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_36, '')
            ylabel(app.UIAxes8_36, '')
            app.UIAxes8_36.XTick = [];
            app.UIAxes8_36.XTickLabel = '';
            app.UIAxes8_36.YTick = [];
            app.UIAxes8_36.YTickLabel = '';
            app.UIAxes8_37 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_37, '')
            ylabel(app.UIAxes8_37, '')
            app.UIAxes8_37.XTick = [];
            app.UIAxes8_37.XTickLabel = '';
            app.UIAxes8_37.YTick = [];
            app.UIAxes8_37.YTickLabel = '';
            app.UIAxes8_38 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_38, '')
            ylabel(app.UIAxes8_38, '')
            app.UIAxes8_38.XTick = [];
            app.UIAxes8_38.XTickLabel = '';
            app.UIAxes8_38.YTick = [];
            app.UIAxes8_38.YTickLabel = '';
            app.UIAxes8_39 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_39, '')
            ylabel(app.UIAxes8_39, '')
            app.UIAxes8_39.XTick = [];
            app.UIAxes8_39.XTickLabel = '';
            app.UIAxes8_39.YTick = [];
            app.UIAxes8_39.YTickLabel = '';
            app.UIAxes8_40 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_40, '')
            ylabel(app.UIAxes8_40, '')
            app.UIAxes8_40.XTick = [];
            app.UIAxes8_40.XTickLabel = '';
            app.UIAxes8_40.YTick = [];
            app.UIAxes8_40.YTickLabel = '';
            app.UIAxes8_41 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_41, '')
            ylabel(app.UIAxes8_41, '')
            app.UIAxes8_41.XTick = [];
            app.UIAxes8_41.XTickLabel = '';
            app.UIAxes8_41.YTick = [];
            app.UIAxes8_41.YTickLabel = '';
            app.UIAxes8_42 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_42, '')
            ylabel(app.UIAxes8_42, '')
            app.UIAxes8_42.XTick = [];
            app.UIAxes8_42.XTickLabel = '';
            app.UIAxes8_42.YTick = [];
            app.UIAxes8_42.YTickLabel = '';
            app.UIAxes8_43 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_43, '')
            ylabel(app.UIAxes8_43, '')
            app.UIAxes8_43.XTick = [];
            app.UIAxes8_43.XTickLabel = '';
            app.UIAxes8_43.YTick = [];
            app.UIAxes8_43.YTickLabel = '';
            app.UIAxes8_44 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_44, '')
            ylabel(app.UIAxes8_44, '')
            app.UIAxes8_44.XTick = [];
            app.UIAxes8_44.XTickLabel = '';
            app.UIAxes8_44.YTick = [];
            app.UIAxes8_44.YTickLabel = '';
            app.UIAxes8_45 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_45, '')
            ylabel(app.UIAxes8_45, '')
            app.UIAxes8_45.XTick = [];
            app.UIAxes8_45.XTickLabel = '';
            app.UIAxes8_45.YTick = [];
            app.UIAxes8_45.YTickLabel = '';
            app.UIAxes8_46 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_46, '')
            ylabel(app.UIAxes8_46, '')
            app.UIAxes8_46.XTick = [];
            app.UIAxes8_46.XTickLabel = '';
            app.UIAxes8_46.YTick = [];
            app.UIAxes8_46.YTickLabel = '';
            app.UIAxes8_47 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_47, '')
            ylabel(app.UIAxes8_47, '')
            app.UIAxes8_47.XTick = [];
            app.UIAxes8_47.XTickLabel = '';
            app.UIAxes8_47.YTick = [];
            app.UIAxes8_47.YTickLabel = '';
            app.UIAxes8_48 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_48, '')
            ylabel(app.UIAxes8_48, '')
            app.UIAxes8_48.XTick = [];
            app.UIAxes8_48.XTickLabel = '';
            app.UIAxes8_48.YTick = [];
            app.UIAxes8_48.YTickLabel = '';
            app.UIAxes8_49 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_49, '')
            ylabel(app.UIAxes8_49, '')
            app.UIAxes8_49.XTick = [];
            app.UIAxes8_49.XTickLabel = '';
            app.UIAxes8_49.YTick = [];
            app.UIAxes8_49.YTickLabel = '';
            app.UIAxes8_50 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_50, '')
            ylabel(app.UIAxes8_50, '')
            app.UIAxes8_50.XTick = [];
            app.UIAxes8_50.XTickLabel = '';
            app.UIAxes8_50.YTick = [];
            app.UIAxes8_50.YTickLabel = '';
            app.UIAxes8_51 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_51, '')
            ylabel(app.UIAxes8_51, '')
            app.UIAxes8_51.XTick = [];
            app.UIAxes8_51.XTickLabel = '';
            app.UIAxes8_51.YTick = [];
            app.UIAxes8_51.YTickLabel = '';
            app.UIAxes8_52 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_52, '')
            ylabel(app.UIAxes8_52, '')
            app.UIAxes8_52.XTick = [];
            app.UIAxes8_52.XTickLabel = '';
            app.UIAxes8_52.YTick = [];
            app.UIAxes8_52.YTickLabel = '';
            app.UIAxes8_53 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_53, '')
            ylabel(app.UIAxes8_53, '')
            app.UIAxes8_53.XTick = [];
            app.UIAxes8_53.XTickLabel = '';
            app.UIAxes8_53.YTick = [];
            app.UIAxes8_53.YTickLabel = '';
            app.UIAxes8_54 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_54, '')
            ylabel(app.UIAxes8_54, '')
            app.UIAxes8_54.XTick = [];
            app.UIAxes8_54.XTickLabel = '';
            app.UIAxes8_54.YTick = [];
            app.UIAxes8_54.YTickLabel = '';
            app.UIAxes8_55 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_55, '')
            ylabel(app.UIAxes8_55, '')
            app.UIAxes8_55.XTick = [];
            app.UIAxes8_55.XTickLabel = '';
            app.UIAxes8_55.YTick = [];
            app.UIAxes8_55.YTickLabel = '';
            app.UIAxes8_56 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_56, '')
            ylabel(app.UIAxes8_56, '')
            app.UIAxes8_56.XTick = [];
            app.UIAxes8_56.XTickLabel = '';
            app.UIAxes8_56.YTick = [];
            app.UIAxes8_56.YTickLabel = '';
            app.UIAxes8_57 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_57, '')
            ylabel(app.UIAxes8_57, '')
            app.UIAxes8_57.XTick = [];
            app.UIAxes8_57.XTickLabel = '';
            app.UIAxes8_57.YTick = [];
            app.UIAxes8_57.YTickLabel = '';
            app.UIAxes8_58 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_58, '')
            ylabel(app.UIAxes8_58, '')
            app.UIAxes8_58.XTick = [];
            app.UIAxes8_58.XTickLabel = '';
            app.UIAxes8_58.YTick = [];
            app.UIAxes8_58.YTickLabel = '';
            app.UIAxes8_59 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_59, '')
            ylabel(app.UIAxes8_59, '')
            app.UIAxes8_59.XTick = [];
            app.UIAxes8_59.XTickLabel = '';
            app.UIAxes8_59.YTick = [];
            app.UIAxes8_59.YTickLabel = '';
            app.UIAxes8_60 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_60, '')
            ylabel(app.UIAxes8_60, '')
            app.UIAxes8_60.XTick = [];
            app.UIAxes8_60.XTickLabel = '';
            app.UIAxes8_60.YTick = [];
            app.UIAxes8_60.YTickLabel = '';
            app.UIAxes8_61 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_61, '')
            ylabel(app.UIAxes8_61, '')
            app.UIAxes8_61.XTick = [];
            app.UIAxes8_61.XTickLabel = '';
            app.UIAxes8_61.YTick = [];
            app.UIAxes8_61.YTickLabel = '';
            app.UIAxes8_62 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_62, '')
            ylabel(app.UIAxes8_62, '')
            app.UIAxes8_62.XTick = [];
            app.UIAxes8_62.XTickLabel = '';
            app.UIAxes8_62.YTick = [];
            app.UIAxes8_62.YTickLabel = '';
            app.UIAxes8_63 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_63, '')
            ylabel(app.UIAxes8_63, '')
            app.UIAxes8_63.XTick = [];
            app.UIAxes8_63.XTickLabel = '';
            app.UIAxes8_63.YTick = [];
            app.UIAxes8_63.YTickLabel = '';
            app.UIAxes8_64 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes8_64, '')
            ylabel(app.UIAxes8_64, '')
            app.UIAxes8_64.XTick = [];
            app.UIAxes8_64.XTickLabel = '';
            app.UIAxes8_64.YTick = [];
            app.UIAxes8_64.YTickLabel = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_tjx_3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end